classdef RealTimeCine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GitHubHyperlink               matlab.ui.control.Hyperlink
        Panel                         matlab.ui.container.Panel
        RButtonEDV2                   matlab.ui.control.Button
        RButtonESV2                   matlab.ui.control.Button
        RButtonEDV1                   matlab.ui.control.Button
        RButtonESV1                   matlab.ui.control.Button
        EDV2ViewField                 matlab.ui.control.EditField
        ESV2ViewField                 matlab.ui.control.EditField
        EDV1ViewField                 matlab.ui.control.EditField
        ESV1ViewField                 matlab.ui.control.EditField
        ExpirationLabel               matlab.ui.control.Label
        InspirationLabel              matlab.ui.control.Label
        EDV2Button                    matlab.ui.control.Button
        ESV2Button                    matlab.ui.control.Button
        EDV1Button                    matlab.ui.control.Button
        ESV1Button                    matlab.ui.control.Button
        statusLabel                   matlab.ui.control.Label
        StatusImage                   matlab.ui.control.Image
        BackgroundBox                 matlab.ui.control.TextArea
        MessageBox                    matlab.ui.control.TextArea
        TabGroup1                     matlab.ui.container.TabGroup
        DataTab                       matlab.ui.container.Tab
        DCMInfoPanel                  matlab.ui.container.Panel
        DCMInfo                       matlab.ui.control.TextArea
        ImageInfoPanel                matlab.ui.container.Panel
        DicomdirectoryEditField       matlab.ui.control.EditField
        DicomdirectoryEditFieldLabel  matlab.ui.control.Label
        timeframesEditField           matlab.ui.control.NumericEditField
        timeframesEditFieldLabel      matlab.ui.control.Label
        slicesEditField               matlab.ui.control.NumericEditField
        slicesEditFieldLabel          matlab.ui.control.Label
        imagesEditField               matlab.ui.control.NumericEditField
        imagesEditFieldLabel          matlab.ui.control.Label
        dimyEditField                 matlab.ui.control.NumericEditField
        dimyEditFieldLabel            matlab.ui.control.Label
        dimxEditField                 matlab.ui.control.NumericEditField
        dimxEditFieldLabel            matlab.ui.control.Label
        CineTab                       matlab.ui.container.Tab
        SliceDown                     matlab.ui.control.Button
        SliceUp                       matlab.ui.control.Button
        SliceLabel                    matlab.ui.control.Label
        SliceListViewField6           matlab.ui.control.NumericEditField
        SliceListViewField5           matlab.ui.control.NumericEditField
        SliceListViewField4           matlab.ui.control.NumericEditField
        SliceListViewField3           matlab.ui.control.NumericEditField
        SliceListViewField2           matlab.ui.control.NumericEditField
        SliceListViewField1           matlab.ui.control.NumericEditField
        S6EditField                   matlab.ui.control.NumericEditField
        Left6                         matlab.ui.control.Button
        Right6                        matlab.ui.control.Button
        S5EditField                   matlab.ui.control.NumericEditField
        Left5                         matlab.ui.control.Button
        Right5                        matlab.ui.control.Button
        S4EditField                   matlab.ui.control.NumericEditField
        Left4                         matlab.ui.control.Button
        Right4                        matlab.ui.control.Button
        S3EditField                   matlab.ui.control.NumericEditField
        Left3                         matlab.ui.control.Button
        Right3                        matlab.ui.control.Button
        S2EditField                   matlab.ui.control.NumericEditField
        Left2                         matlab.ui.control.Button
        Right2                        matlab.ui.control.Button
        S1EditField                   matlab.ui.control.NumericEditField
        Left1                         matlab.ui.control.Button
        Right1                        matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        MovieFig_65                   matlab.ui.control.UIAxes
        MovieFig_55                   matlab.ui.control.UIAxes
        MovieFig_45                   matlab.ui.control.UIAxes
        MovieFig_35                   matlab.ui.control.UIAxes
        MovieFig_25                   matlab.ui.control.UIAxes
        MovieFig_15                   matlab.ui.control.UIAxes
        MovieFig_69                   matlab.ui.control.UIAxes
        MovieFig_67                   matlab.ui.control.UIAxes
        MovieFig_68                   matlab.ui.control.UIAxes
        MovieFig_66                   matlab.ui.control.UIAxes
        MovieFig_59                   matlab.ui.control.UIAxes
        MovieFig_58                   matlab.ui.control.UIAxes
        MovieFig_56                   matlab.ui.control.UIAxes
        MovieFig_57                   matlab.ui.control.UIAxes
        MovieFig_64                   matlab.ui.control.UIAxes
        MovieFig_63                   matlab.ui.control.UIAxes
        MovieFig_62                   matlab.ui.control.UIAxes
        MovieFig_54                   matlab.ui.control.UIAxes
        MovieFig_53                   matlab.ui.control.UIAxes
        MovieFig_52                   matlab.ui.control.UIAxes
        MovieFig_49                   matlab.ui.control.UIAxes
        MovieFig_48                   matlab.ui.control.UIAxes
        MovieFig_47                   matlab.ui.control.UIAxes
        MovieFig_46                   matlab.ui.control.UIAxes
        MovieFig_44                   matlab.ui.control.UIAxes
        MovieFig_43                   matlab.ui.control.UIAxes
        MovieFig_42                   matlab.ui.control.UIAxes
        MovieFig_39                   matlab.ui.control.UIAxes
        MovieFig_38                   matlab.ui.control.UIAxes
        MovieFig_37                   matlab.ui.control.UIAxes
        MovieFig_36                   matlab.ui.control.UIAxes
        MovieFig_34                   matlab.ui.control.UIAxes
        MovieFig_33                   matlab.ui.control.UIAxes
        MovieFig_32                   matlab.ui.control.UIAxes
        MovieFig_29                   matlab.ui.control.UIAxes
        MovieFig_28                   matlab.ui.control.UIAxes
        MovieFig_27                   matlab.ui.control.UIAxes
        MovieFig_26                   matlab.ui.control.UIAxes
        MovieFig_24                   matlab.ui.control.UIAxes
        MovieFig_23                   matlab.ui.control.UIAxes
        MovieFig_22                   matlab.ui.control.UIAxes
        MovieFig_61                   matlab.ui.control.UIAxes
        MovieFig_51                   matlab.ui.control.UIAxes
        MovieFig_41                   matlab.ui.control.UIAxes
        MovieFig_31                   matlab.ui.control.UIAxes
        MovieFig_21                   matlab.ui.control.UIAxes
        MovieFig_19                   matlab.ui.control.UIAxes
        MovieFig_18                   matlab.ui.control.UIAxes
        MovieFig_17                   matlab.ui.control.UIAxes
        MovieFig_16                   matlab.ui.control.UIAxes
        MovieFig_14                   matlab.ui.control.UIAxes
        MovieFig_13                   matlab.ui.control.UIAxes
        MovieFig_12                   matlab.ui.control.UIAxes
        MovieFig_11                   matlab.ui.control.UIAxes
        Navigators1Tab                matlab.ui.container.Tab
        AlignValleyButton             matlab.ui.control.Button
        AlignPeaksButton              matlab.ui.control.Button
        LoadPPUButton                 matlab.ui.control.Button
        PhysioFig5                    matlab.ui.control.UIAxes
        PhysioFig4                    matlab.ui.control.UIAxes
        Navigators2Tab                matlab.ui.container.Tab
        PhysioFig1                    matlab.ui.control.UIAxes
        PhysioFig3                    matlab.ui.control.UIAxes
        PhysioFig2                    matlab.ui.control.UIAxes
        MOLLITab                      matlab.ui.container.Tab
        Lamp6                         matlab.ui.control.Lamp
        ExportMOLLIButton             matlab.ui.control.Button
        MOLLIInversionTimesEditField  matlab.ui.control.EditField
        MOLLIInversionTimesEditFieldLabel  matlab.ui.control.Label
        MOLLITriggerTimesEditField    matlab.ui.control.EditField
        MOLLITriggerTimesEditFieldLabel  matlab.ui.control.Label
        MOLLINumberofslicesEditField  matlab.ui.control.NumericEditField
        MOLLINumberofslicesEditFieldLabel  matlab.ui.control.Label
        MOLLIDCMdirectoryEditField    matlab.ui.control.EditField
        MOLLIDCMdirectoryEditFieldLabel  matlab.ui.control.Label
        MOLLIDCMInfoPanel             matlab.ui.container.Panel
        MOLLIDCMInfo                  matlab.ui.control.TextArea
        Lamp5                         matlab.ui.control.Lamp
        LoadMOLLIButton               matlab.ui.control.Button
        SettingsTab                   matlab.ui.container.Tab
        ColorsLabel                   matlab.ui.control.Label
        ColorsButtonGroup             matlab.ui.container.ButtonGroup
        BlackWhiteButton              matlab.ui.control.RadioButton
        ClassicButton                 matlab.ui.control.RadioButton
        RealTimeCineLabel             matlab.ui.control.Label
        Credits                       matlab.ui.control.Label
        TulipImage                    matlab.ui.control.Image
        TabGroup0                     matlab.ui.container.TabGroup
        DashboardTab                  matlab.ui.container.Tab
        LoadSelectButton              matlab.ui.control.Button
        Lamp3                         matlab.ui.control.Lamp
        Lamp4                         matlab.ui.control.Lamp
        ExportDCMButton               matlab.ui.control.Button
        Lamp2                         matlab.ui.control.Lamp
        TracepositionsPanel           matlab.ui.container.Panel
        Tracey2EditField              matlab.ui.control.NumericEditField
        Tracey2EditFieldLabel         matlab.ui.control.Label
        Tracex2EditField              matlab.ui.control.NumericEditField
        Tracex2EditFieldLabel         matlab.ui.control.Label
        Tracey1EditField              matlab.ui.control.NumericEditField
        Tracey1EditFieldLabel         matlab.ui.control.Label
        Tracex1EditField              matlab.ui.control.NumericEditField
        Tracex1EditFieldLabel         matlab.ui.control.Label
        TraceyEditField               matlab.ui.control.NumericEditField
        TraceyEditFieldLabel          matlab.ui.control.Label
        TracexEditField               matlab.ui.control.NumericEditField
        TracexEditFieldLabel          matlab.ui.control.Label
        PhysioButton                  matlab.ui.control.Button
        ImageIntensityPanel           matlab.ui.container.Panel
        maxEditField                  matlab.ui.control.NumericEditField
        maxEditFieldLabel             matlab.ui.control.Label
        minEditField                  matlab.ui.control.NumericEditField
        minEditFieldLabel             matlab.ui.control.Label
        ImagedimensionsPanel          matlab.ui.container.Panel
        y2EditField                   matlab.ui.control.NumericEditField
        y2EditFieldLabel              matlab.ui.control.Label
        y1EditField                   matlab.ui.control.NumericEditField
        y1EditFieldLabel              matlab.ui.control.Label
        x2EditField                   matlab.ui.control.NumericEditField
        x2EditFieldLabel              matlab.ui.control.Label
        x1EditField                   matlab.ui.control.NumericEditField
        x1EditFieldLabel              matlab.ui.control.Label
        Lamp1                         matlab.ui.control.Lamp
        ExitButton                    matlab.ui.control.Button
        LoadDataButton                matlab.ui.control.Button
    end


    % Real Time Cine application
    %
    % Gustav Strijkers
    % Amsterdam UMC, Academic Medical Center (AMC)
    % Amsterdam, the Netherlands
    % g.j.strijkers@amsterdamumc.nl
    %
    % 
    %
    % Version: July, 2025
    % Matlab 2025a
    %
    %
    % Version 1
    % - initial commit
    %
    % Version 2
    % - MOLLI inversion time correction
    %
    % Version 2.1
    % - Added auto aligment of respiration states
    %
    % Version 2.2 (March 2023)
    % - Bug fixes
    %
    % Version 3.0 (May 2025(
    % - Making the version ready for public 
    %
    %
    %
    %


    % global variables within the app
    properties (Access = private)

        %#ok<*ST2NM>

        % Version
        appVersion = "3.0.0"            % software version
        
        % Directory & filename
        dicom_import_path               % dicom import path
        dicom_export_path               % dicom export path
        molli_dicom_import_path         % molli dicom import path
        molli_dicom_export_path         % molli dicom export path
        appdir                          % application directory
        olddir                          % starting directory
        
        % Data file
        datafile                        % dcm data file
        molli_datafile                  % molli dcm data file
        data                            % dcm data
        molli_data                      % molli dcm data
        img                             % image data, unsorted
        im                              % image data, sorted, resized
        valid_data                      % true or false
        valid_load                      % true or false
        molli_valid_data                % true or false
        molli_valid_load                % true or false   
        molli_img                       % molli images
        molli_info                      % molli dicom info
        mintriggertime                  % minimum trigger time / reference value
        
        % Acquisition
        dimx                            % nr readout points
        dimy                            % nr phase encoding points
        dimz                            % nr slices
        nr_images                       % total number of images
        nr_frames                       % number of time frames
        spatial                         % spatial information from dicom
        TR                              % repetition time
        
        % Physio
        meansignals                     % mean of images
        horsignals                      % horizontal line
        vertsignals                     % vertical line
        diagsignals                     % diagonal line
        
        % Viewing & plotting
       
        horline                         % horizontal line
        vertline                        % vertical line
        diagline                        % diagonal line
        center_row                      % list of rows for plotting
        row_cnt                         % first row 
        row_list                        % list of all possible slices/rows
        center_column                   % center column
        column_cnt                      % first column
        column_list                     % list of all possible frames/columns
        linethickness = 3               % nagivator line thickness

        % Colors
        green                           % other shade of green
        brightgreen                     % green
        red                             % other shade of red
        brightred                       % red
        blue                            % blue
        yellow                          % yellow
        orange                          % orange
        grey                            % grey
        black                           % black
        lightblue                       % lightblue
        lightgrey                       % lightgrey
        guiBlack                        % app black
        guiGrey                         % app grey
        guiMiddleGrey                   % app middle grey
        guiDarkGrey                     % app dark grey
        guiWhite                        % app white
        guiBlackBlue                    % app black/blueish
         
        % List of key images
        EDV1                            % End systolic volume, 1 = inspiration
        ESV1                            % End diastolic volume, 1 = inspiration
        EDV2                            % End systolic volume, 2 = expiration
        ESV2                            % End diastolic volume, 2 = expiration
        
        % Images
        im11;im12;im13;im14;im15;im16;im17;im18;im19    % row 1
        im21;im22;im23;im24;im25;im26;im27;im28;im29    % row 2
        im31;im32;im33;im34;im35;im36;im37;im38;im39    % row 3
        im41;im42;im43;im44;im45;im46;im47;im48;im49    % row 4
        im51;im52;im53;im54;im55;im56;im57;im58;im59    % row 5
        im61;im62;im63;im64;im65;im66;im67;im68;im69    % row 6
        
    end


    methods (Access = private)


        % -------------------------------------------------
        %
        %       App layout, buttons and lights controls
        %
        % -------------------------------------------------


        % Set app layout properties
        function SetAppLayoutProperties(app)

            app.Credits.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);
            
            app.TulipImage.ImageSource = 'AmsterdamUMC.png';
            
            app.LoadDataButton.Icon = 'disk.png';
            app.ExportDCMButton.Icon = 'disk.png';
            app.LoadSelectButton.Icon = 'disk.png';
            app.LoadPPUButton.Icon = 'disk.png';
            app.LoadMOLLIButton.Icon = 'disk.png';
            app.ExportMOLLIButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.PhysioButton.Icon = 'filter.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            
            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.black = 'Black';
            app.lightblue = '#DEE6EB';
            app.lightgrey = '#F0F0F0';
            app.grey = [0.94,0.94,0.94];
            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.9,0.9,0.9];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];
            
            % Switch off plot interactivity
            app.MovieFig_11.Toolbar.Visible = 'off';    app.MovieFig_12.Toolbar.Visible = 'off';    app.MovieFig_13.Toolbar.Visible = 'off';
            app.MovieFig_14.Toolbar.Visible = 'off';    app.MovieFig_15.Toolbar.Visible = 'off';    app.MovieFig_16.Toolbar.Visible = 'off';
            app.MovieFig_17.Toolbar.Visible = 'off';    app.MovieFig_18.Toolbar.Visible = 'off';    app.MovieFig_19.Toolbar.Visible = 'off'; 
            app.MovieFig_21.Toolbar.Visible = 'off';    app.MovieFig_22.Toolbar.Visible = 'off';    app.MovieFig_23.Toolbar.Visible = 'off';
            app.MovieFig_24.Toolbar.Visible = 'off';    app.MovieFig_25.Toolbar.Visible = 'off';    app.MovieFig_26.Toolbar.Visible = 'off';
            app.MovieFig_27.Toolbar.Visible = 'off';    app.MovieFig_28.Toolbar.Visible = 'off';    app.MovieFig_29.Toolbar.Visible = 'off'; 
            app.MovieFig_31.Toolbar.Visible = 'off';    app.MovieFig_32.Toolbar.Visible = 'off';    app.MovieFig_33.Toolbar.Visible = 'off';
            app.MovieFig_34.Toolbar.Visible = 'off';    app.MovieFig_35.Toolbar.Visible = 'off';    app.MovieFig_36.Toolbar.Visible = 'off';
            app.MovieFig_37.Toolbar.Visible = 'off';    app.MovieFig_38.Toolbar.Visible = 'off';    app.MovieFig_39.Toolbar.Visible = 'off'; 
            app.MovieFig_41.Toolbar.Visible = 'off';    app.MovieFig_42.Toolbar.Visible = 'off';    app.MovieFig_43.Toolbar.Visible = 'off';
            app.MovieFig_44.Toolbar.Visible = 'off';    app.MovieFig_45.Toolbar.Visible = 'off';    app.MovieFig_46.Toolbar.Visible = 'off';
            app.MovieFig_47.Toolbar.Visible = 'off';    app.MovieFig_48.Toolbar.Visible = 'off';    app.MovieFig_49.Toolbar.Visible = 'off'; 
            app.MovieFig_51.Toolbar.Visible = 'off';    app.MovieFig_52.Toolbar.Visible = 'off';    app.MovieFig_53.Toolbar.Visible = 'off';
            app.MovieFig_54.Toolbar.Visible = 'off';    app.MovieFig_55.Toolbar.Visible = 'off';    app.MovieFig_56.Toolbar.Visible = 'off';
            app.MovieFig_57.Toolbar.Visible = 'off';    app.MovieFig_58.Toolbar.Visible = 'off';    app.MovieFig_59.Toolbar.Visible = 'off'; 
            app.MovieFig_61.Toolbar.Visible = 'off';    app.MovieFig_62.Toolbar.Visible = 'off';    app.MovieFig_63.Toolbar.Visible = 'off';
            app.MovieFig_64.Toolbar.Visible = 'off';    app.MovieFig_65.Toolbar.Visible = 'off';    app.MovieFig_66.Toolbar.Visible = 'off';
            app.MovieFig_67.Toolbar.Visible = 'off';    app.MovieFig_68.Toolbar.Visible = 'off';    app.MovieFig_69.Toolbar.Visible = 'off';
            app.MovieFig_11.Interactions = [];          app.MovieFig_12.Interactions = [];          app.MovieFig_13.Interactions = [];
            app.MovieFig_14.Interactions = [];          app.MovieFig_15.Interactions = [];          app.MovieFig_16.Interactions = [];
            app.MovieFig_17.Interactions = [];          app.MovieFig_18.Interactions = [];          app.MovieFig_19.Interactions = [];
            app.MovieFig_21.Interactions = [];          app.MovieFig_22.Interactions = [];          app.MovieFig_23.Interactions = [];
            app.MovieFig_24.Interactions = [];          app.MovieFig_25.Interactions = [];          app.MovieFig_26.Interactions = [];
            app.MovieFig_27.Interactions = [];          app.MovieFig_28.Interactions = [];          app.MovieFig_29.Interactions = [];
            app.MovieFig_31.Interactions = [];          app.MovieFig_32.Interactions = [];          app.MovieFig_33.Interactions = [];
            app.MovieFig_34.Interactions = [];          app.MovieFig_35.Interactions = [];          app.MovieFig_36.Interactions = [];
            app.MovieFig_37.Interactions = [];          app.MovieFig_38.Interactions = [];          app.MovieFig_39.Interactions = [];
            app.MovieFig_41.Interactions = [];          app.MovieFig_42.Interactions = [];          app.MovieFig_43.Interactions = [];
            app.MovieFig_44.Interactions = [];          app.MovieFig_45.Interactions = [];          app.MovieFig_46.Interactions = [];
            app.MovieFig_47.Interactions = [];          app.MovieFig_48.Interactions = [];          app.MovieFig_49.Interactions = [];
            app.MovieFig_51.Interactions = [];          app.MovieFig_52.Interactions = [];          app.MovieFig_53.Interactions = [];
            app.MovieFig_54.Interactions = [];          app.MovieFig_55.Interactions = [];          app.MovieFig_56.Interactions = [];
            app.MovieFig_57.Interactions = [];          app.MovieFig_58.Interactions = [];          app.MovieFig_59.Interactions = [];
            app.MovieFig_61.Interactions = [];          app.MovieFig_62.Interactions = [];          app.MovieFig_63.Interactions = [];
            app.MovieFig_64.Interactions = [];          app.MovieFig_65.Interactions = [];          app.MovieFig_66.Interactions = [];
            app.MovieFig_67.Interactions = [];          app.MovieFig_68.Interactions = [];          app.MovieFig_69.Interactions = [];
            drawnow;
                             
            app.PhysioFig1.Toolbar.Visible = 'off';
            app.PhysioFig1.Interactions = []; 
            app.PhysioFig2.Toolbar.Visible = 'off';
            app.PhysioFig2.Interactions = []; 
            app.PhysioFig3.Toolbar.Visible = 'off';
            app.PhysioFig3.Interactions = []; 
            app.PhysioFig4.Toolbar.Visible = 'off';
            app.PhysioFig4.Interactions = []; 
            app.PhysioFig5.Toolbar.Visible = 'off';
            app.PhysioFig5.Interactions = []; 
            drawnow;
            
            % set buttons
            app.LoadDataButton.Enable = 'on';
            app.Lamp1.Color = app.red;
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            app.SetStatus(0);
            
        end


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            % The colors
            backgroundColor = guiColors{1};
            fontColor = guiColors{2};
            boxColor = guiColors{3};
            figureColor = guiColors{4};
       
            % Main tabs
            app.UIFigure.Color = backgroundColor;
            app.DashboardTab.BackgroundColor = backgroundColor;
            app.DataTab.BackgroundColor = backgroundColor;
            app.CineTab.BackgroundColor = backgroundColor;
            app.Navigators1Tab.BackgroundColor = backgroundColor;
            app.Navigators2Tab.BackgroundColor = backgroundColor;
            app.MOLLITab.BackgroundColor = backgroundColor;
            app.SettingsTab.BackgroundColor = backgroundColor;
            app.ColorsButtonGroup.BackgroundColor = backgroundColor;
            app.MessageBox.FontColor = boxColor;
            app.MessageBox.BackgroundColor = figureColor;
            app.BackgroundBox.BackgroundColor = figureColor; 

            % Panels
            app.ImagedimensionsPanel.BackgroundColor = backgroundColor;
            app.ImageIntensityPanel.BackgroundColor = backgroundColor;
            app.TracepositionsPanel.BackgroundColor = backgroundColor;
            app.ImageInfoPanel.BackgroundColor = backgroundColor;
            app.DCMInfoPanel.BackgroundColor = backgroundColor;
            app.MOLLIDCMInfoPanel.BackgroundColor = backgroundColor;
            app.Panel.BackgroundColor = backgroundColor;

            % Fonts
            app.x1EditFieldLabel.FontColor = fontColor;
            app.x2EditFieldLabel.FontColor = fontColor;
            app.y1EditFieldLabel.FontColor = fontColor;
            app.y2EditFieldLabel.FontColor = fontColor;
            app.minEditFieldLabel.FontColor = fontColor;
            app.maxEditFieldLabel.FontColor = fontColor;
            app.TracexEditFieldLabel.FontColor = fontColor;
            app.TraceyEditFieldLabel.FontColor = fontColor;
            app.Tracex1EditFieldLabel.FontColor = fontColor;
            app.Tracex2EditFieldLabel.FontColor = fontColor;
            app.Tracey1EditFieldLabel.FontColor = fontColor;
            app.Tracey2EditFieldLabel.FontColor = fontColor;
            app.DicomdirectoryEditFieldLabel.FontColor = fontColor;
            app.imagesEditFieldLabel.FontColor = fontColor;
            app.dimxEditFieldLabel.FontColor = fontColor;
            app.dimyEditFieldLabel.FontColor = fontColor;
            app.slicesEditFieldLabel.FontColor = fontColor;
            app.timeframesEditFieldLabel.FontColor = fontColor;
            app.InspirationLabel.FontColor = fontColor;
            app.ExpirationLabel.FontColor = fontColor;
            app.MOLLIDCMdirectoryEditFieldLabel.FontColor = fontColor;
            app.MOLLINumberofslicesEditFieldLabel.FontColor = fontColor;
            app.MOLLITriggerTimesEditFieldLabel.FontColor = fontColor;
            app.MOLLIInversionTimesEditFieldLabel.FontColor = fontColor;
            app.ClassicButton.FontColor = fontColor;
            app.BlackWhiteButton.FontColor = fontColor;
            app.SliceLabel.FontColor = fontColor;

            % Figures
            app.PhysioFig1.Color = figureColor;
            app.PhysioFig1.Title.Color = fontColor;
            app.PhysioFig1.XColor = fontColor;
            app.PhysioFig1.YColor = fontColor;

            app.PhysioFig2.Color = figureColor;
            app.PhysioFig2.Title.Color = fontColor;
            app.PhysioFig2.XColor = fontColor;
            app.PhysioFig2.YColor = fontColor;

            app.PhysioFig3.Color = figureColor;
            app.PhysioFig3.Title.Color = fontColor;
            app.PhysioFig3.XColor = fontColor;
            app.PhysioFig3.YColor = fontColor;

            app.PhysioFig4.Color = figureColor;
            app.PhysioFig4.Title.Color = fontColor;
            app.PhysioFig4.XColor = fontColor;
            app.PhysioFig4.YColor = fontColor;

            app.PhysioFig5.Color = figureColor;
            app.PhysioFig5.Title.Color = fontColor;
            app.PhysioFig5.XColor = fontColor;
            app.PhysioFig5.YColor = fontColor;

        end % SetAppColorsFcn


        % App version check
        function CheckVersionFnc(app)

            try

                currentVersion = convertStringsToChars(app.appVersion);

                newestVersions = {"3.3","3.2","3.1","3.0","2.9","2.8","2.7","2.6","2.5","2.4","2.3","2.2","2.1","2.0"};

                webtxt = [];
                ver = 0;
                while isempty(webtxt) && ver<=length(newestVersions)
                    try
                        ver = ver + 1;
                        newVersionFile = strcat("https://github.com/Moby1971/RealTimeCine-v",newestVersions{ver},"/blob/main/appversion.txt");
                        webtxt = webread(newVersionFile);
                    catch
                    end
                end

                pos = strfind(lower(webtxt),"rtc_version=");
                newVersion = webtxt(pos+12:pos+12+strlength(newestVersions{ver})+2);

                app.TextMessage(strcat("Current version = ",currentVersion));
                app.TextMessage(strcat("Newest version = ",newVersion));

                newVersion = strrep(newVersion,".","");
                newVersionNr = str2num(newVersion); 
                currentVersion = strrep(currentVersion,".","");
                currentVersionNr = str2num(currentVersion);

                if currentVersionNr < newVersionNr
                    app.TextMessage('A new version of RealTimeCine is available ...');
                    app.TextMessage('Visit https://github.com/Moby1971/ to update ...');
                else
                    app.TextMessage('RealTimeCine is up to date ...');
                end

            catch ME

                app.TextMessage(ME.message);
                app.TextMessage('Could not check RealTimeCine version ...');

            end

        end % CheckVersionFnc


        % -------------------------------------------------
        %
        %           Image data properties
        %
        % -------------------------------------------------


        % Image parameters
        function ImageParameters(app)

            try

                % Number of images
                app.nr_images = length(app.img);

                % X and Y dimensions
                app.dimx = size(app.img,1);
                app.dimy = size(app.img,2);

                % Determine the number of slices
                s1 = size(unique(app.spatial.PatientPositions(:,1)));
                s2 = size(unique(app.spatial.PatientPositions(:,2)));
                s3 = size(unique(app.spatial.PatientPositions(:,3)));
                app.dimz = max([s1,s2,s3]);
                app.nr_frames = round(app.nr_images/app.dimz);

                % Show image dimensions in app
                app.dimxEditField.Value = app.dimx;
                app.dimyEditField.Value = app.dimy;
                app.slicesEditField.Value = app.dimz;
                app.imagesEditField.Value = app.nr_images;
                app.timeframesEditField.Value = app.nr_frames;

                % List of rows from which the center 6 will be visible
                app.row_list = zeros(256,1);
                for i = 1 : app.dimz
                    app.row_list(i+127) = i;
                end
                app.row_cnt = 128;
                app.center_row(1:6) = app.row_list(128:128+5);

                % List of columns from which the center 9 will be visible
                app.column_list = zeros(256,1);
                for i = 1 : app.dimz
                    app.column_list(i+127) = round(app.nr_frames/2);
                end
                app.center_column(1:6) = app.column_list(128:128+5);

                % Show the row and column numbers
                app.SetRows;
                app.SetColumns;

                % Empty key images lists
                app.EDV1 = [];
                app.ESV1 = [];
                app.EDV2 = [];
                app.ESV2 = [];
                app.EDV1ViewField.Value = '';
                app.ESV1ViewField.Value = '';
                app.EDV2ViewField.Value = '';
                app.ESV2ViewField.Value = '';
                app.ESV1Button.BackgroundColor = app.grey;
                app.EDV1Button.BackgroundColor = app.grey;
                app.ESV2Button.BackgroundColor = app.grey;
                app.EDV2Button.BackgroundColor = app.grey;

                % New image
                app.im = squeeze(app.img);
                app.im = permute(app.im,[3,1,2]);
                app.im = reshape(app.im,app.nr_frames,app.dimz,app.dimx,app.dimy);

                % show first results
                app.x2EditField.Value = app.dimx;
                app.y2EditField.Value = app.dimy;

                app.TracexEditField.Value = round(app.dimx/2);
                app.TraceyEditField.Value = round(app.dimy/2);
                app.Tracex1EditField.Value = 120;
                app.Tracex2EditField.Value = 120;
                app.Tracey1EditField.Value = 10;
                app.Tracey2EditField.Value = 90;
                app.Tracex1EditField.Value(app.Tracex1EditField.Value>app.dimx) = app.dimx;
                app.Tracex2EditField.Value(app.Tracex2EditField.Value>app.dimx) = app.dimx;
                app.Tracey1EditField.Value(app.Tracey1EditField.Value>app.dimy) = app.dimy;
                app.Tracey2EditField.Value(app.Tracey2EditField.Value>app.dimy) = app.dimy;

                app.valid_data = true;

            catch ME

                app.TextMessage(ME.message);
                app.valid_data = false;

            end

        end


        % Image parameters
        function MolliImageParameters(app)
            
            try

                % Number of slices
                sliceposition = zeros(length(app.molli_info));
                for i = 1:length(app.molli_info)
                    sliceposition(i) = app.molli_info{i}.SliceLocation;
                end
                nrofslices = length(unique(sliceposition));
                app.MOLLINumberofslicesEditField.Value = nrofslices;

                % Trigger times
                triggertimes = zeros(length(app.molli_info));
                for i = 1:length(app.molli_info)
                    triggertimes(i) = app.molli_info{i}.TriggerTime;
                end
                uniquetriggertimes = unique(triggertimes);
                app.MOLLITriggerTimesEditField.Value = num2str(uniquetriggertimes);
                app.mintriggertime = min(triggertimes);

                % Inversion delay times
                inversiontimes = zeros(length(app.molli_info));
                for i = 1:length(app.molli_info)
                    inversiontimes(i) = app.molli_info{i}.Private_2005_1572(1);
                end
                uniqueinversiontimes = unique(inversiontimes);
                app.MOLLIInversionTimesEditField.Value = num2str(uniqueinversiontimes);

                app.molli_valid_data = true;

            catch

                app.molli_valid_data = false;

            end
            
        end


        % -------------------------------------------------
        %
        %                    Plots
        %
        % -------------------------------------------------
        
        
        function PlotAllRows(app,fast)
            
            app.PlotRow1(app.center_column(1),app.center_row(1),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,fast);
            app.PlotRow2(app.center_column(2),app.center_row(2),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,fast);
            app.PlotRow3(app.center_column(3),app.center_row(3),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,fast);
            app.PlotRow4(app.center_column(4),app.center_row(4),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,fast);
            app.PlotRow5(app.center_column(5),app.center_row(5),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,fast);
            app.PlotRow6(app.center_column(6),app.center_row(6),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,fast);
            
        end
   
        
        function PlotRow1(app,a,c,x1,x2,y1,y2,flag)
            
            if c > 0
                
                row = c;
                
                if flag == 0
                    
                    minim = app.minEditField.Value;
                    maxim = app.maxEditField.Value;
                    
                    hold(app.MovieFig_11,'on');
                    app.im11 = imshow(squeeze(app.im(a-4,row,:,:)),[minim maxim],'Parent',app.MovieFig_11);
                    app.vertline(1) = plot(app.MovieFig_11,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                    app.horline(1) = plot(app.MovieFig_11,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                    xlim(app.MovieFig_11, [x1 x2]);  ylim(app.MovieFig_11, [y1 y2]);
                    hold(app.MovieFig_11,'off');
                    
                    hold(app.MovieFig_12,'on');
                    app.im12 = imshow(squeeze(app.im(a-3,row,:,:)),[minim maxim],'Parent',app.MovieFig_12);
                    app.diagline(1) = plot(app.MovieFig_12,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                    xlim(app.MovieFig_12, [x1 x2]);  ylim(app.MovieFig_12, [y1 y2]);
                    hold(app.MovieFig_12,'off');
                    
                    app.im13 = imshow(squeeze(app.im(a-2,row,:,:)),[minim maxim],'Parent',app.MovieFig_13);
                    app.im14 = imshow(squeeze(app.im(a-1,row,:,:)),[minim maxim],'Parent',app.MovieFig_14);
                    app.im15 = imshow(squeeze(app.im(a  ,row,:,:)),[minim maxim],'Parent',app.MovieFig_15);
                    app.im16 = imshow(squeeze(app.im(a+1,row,:,:)),[minim maxim],'Parent',app.MovieFig_16);
                    app.im17 = imshow(squeeze(app.im(a+2,row,:,:)),[minim maxim],'Parent',app.MovieFig_17);
                    app.im18 = imshow(squeeze(app.im(a+3,row,:,:)),[minim maxim],'Parent',app.MovieFig_18);
                    app.im19 = imshow(squeeze(app.im(a+4,row,:,:)),[minim maxim],'Parent',app.MovieFig_19);
                    
                    xlim(app.MovieFig_12, [x1 x2]);  ylim(app.MovieFig_12, [y1 y2]);
                    xlim(app.MovieFig_13, [x1 x2]);  ylim(app.MovieFig_13, [y1 y2]);
                    xlim(app.MovieFig_14, [x1 x2]);  ylim(app.MovieFig_14, [y1 y2]);
                    xlim(app.MovieFig_15, [x1 x2]);  ylim(app.MovieFig_15, [y1 y2]);
                    xlim(app.MovieFig_16, [x1 x2]);  ylim(app.MovieFig_16, [y1 y2]);
                    xlim(app.MovieFig_17, [x1 x2]);  ylim(app.MovieFig_17, [y1 y2]);
                    xlim(app.MovieFig_18, [x1 x2]);  ylim(app.MovieFig_18, [y1 y2]);
                    xlim(app.MovieFig_19, [x1 x2]);  ylim(app.MovieFig_19, [y1 y2]);
                    
                    drawnow;

                else

                    set(app.im11, 'CData', squeeze(app.im(a-4,row,:,:)));
                    set(app.im12, 'CData', squeeze(app.im(a-3,row,:,:)));
                    set(app.im13, 'CData', squeeze(app.im(a-2,row,:,:)));
                    set(app.im14, 'CData', squeeze(app.im(a-1,row,:,:)));
                    set(app.im15, 'CData', squeeze(app.im(a  ,row,:,:)));
                    set(app.im16, 'CData', squeeze(app.im(a+1,row,:,:)));
                    set(app.im17, 'CData', squeeze(app.im(a+2,row,:,:)));
                    set(app.im18, 'CData', squeeze(app.im(a+3,row,:,:)));
                    set(app.im19, 'CData', squeeze(app.im(a+4,row,:,:)));


                end

            else

                set(app.im11, 'CData', zeros(app.dimx,app.dimy));
                set(app.im12, 'CData', zeros(app.dimx,app.dimy));
                set(app.im13, 'CData', zeros(app.dimx,app.dimy));
                set(app.im14, 'CData', zeros(app.dimx,app.dimy));
                set(app.im15, 'CData', zeros(app.dimx,app.dimy));
                set(app.im16, 'CData', zeros(app.dimx,app.dimy));
                set(app.im17, 'CData', zeros(app.dimx,app.dimy));
                set(app.im18, 'CData', zeros(app.dimx,app.dimy));
                set(app.im19, 'CData', zeros(app.dimx,app.dimy));

            end
            
        end
        
        
        function PlotRow2(app,a,c,x1,x2,y1,y2,flag)
            
            if c > 0
                
                row = c;
                
                if flag == 0
                    
                    minim = app.minEditField.Value;
                    maxim = app.maxEditField.Value;

                    hold(app.MovieFig_21,'on');
                    app.im21 = imshow(squeeze(app.im(a-4,row,:,:)),[minim maxim],'Parent',app.MovieFig_21);
                    app.vertline(2) = plot(app.MovieFig_21,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                    app.horline(2) = plot(app.MovieFig_21,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                    xlim(app.MovieFig_21, [x1 x2]);  ylim(app.MovieFig_21, [y1 y2]);
                    hold(app.MovieFig_21,'off');
                    
                    hold(app.MovieFig_22,'on');
                    app.im22 = imshow(squeeze(app.im(a-3,row,:,:)),[minim maxim],'Parent',app.MovieFig_22);
                    app.diagline(2) = plot(app.MovieFig_22,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                    xlim(app.MovieFig_22, [x1 x2]);  ylim(app.MovieFig_22, [y1 y2]);
                    hold(app.MovieFig_22,'off');
 
                    app.im23 = imshow(squeeze(app.im(a-2,row,:,:)),[minim maxim],'Parent',app.MovieFig_23);
                    app.im24 = imshow(squeeze(app.im(a-1,row,:,:)),[minim maxim],'Parent',app.MovieFig_24);
                    app.im25 = imshow(squeeze(app.im(a  ,row,:,:)),[minim maxim],'Parent',app.MovieFig_25);
                    app.im26 = imshow(squeeze(app.im(a+1,row,:,:)),[minim maxim],'Parent',app.MovieFig_26);
                    app.im27 = imshow(squeeze(app.im(a+2,row,:,:)),[minim maxim],'Parent',app.MovieFig_27);
                    app.im28 = imshow(squeeze(app.im(a+3,row,:,:)),[minim maxim],'Parent',app.MovieFig_28);
                    app.im29 = imshow(squeeze(app.im(a+4,row,:,:)),[minim maxim],'Parent',app.MovieFig_29);
                    
                    xlim(app.MovieFig_21, [x1 x2]);  ylim(app.MovieFig_21, [y1 y2]);
                    xlim(app.MovieFig_22, [x1 x2]);  ylim(app.MovieFig_22, [y1 y2]);
                    xlim(app.MovieFig_23, [x1 x2]);  ylim(app.MovieFig_23, [y1 y2]);
                    xlim(app.MovieFig_24, [x1 x2]);  ylim(app.MovieFig_24, [y1 y2]);
                    xlim(app.MovieFig_25, [x1 x2]);  ylim(app.MovieFig_25, [y1 y2]);
                    xlim(app.MovieFig_26, [x1 x2]);  ylim(app.MovieFig_26, [y1 y2]);
                    xlim(app.MovieFig_27, [x1 x2]);  ylim(app.MovieFig_27, [y1 y2]);
                    xlim(app.MovieFig_28, [x1 x2]);  ylim(app.MovieFig_28, [y1 y2]);
                    xlim(app.MovieFig_29, [x1 x2]);  ylim(app.MovieFig_29, [y1 y2]);
                    
                    drawnow;
                    
                else
                    
                    set(app.im21, 'CData', squeeze(app.im(a-4,row,:,:)));
                    set(app.im22, 'CData', squeeze(app.im(a-3,row,:,:)));
                    set(app.im23, 'CData', squeeze(app.im(a-2,row,:,:)));
                    set(app.im24, 'CData', squeeze(app.im(a-1,row,:,:)));
                    set(app.im25, 'CData', squeeze(app.im(a  ,row,:,:)));
                    set(app.im26, 'CData', squeeze(app.im(a+1,row,:,:)));
                    set(app.im27, 'CData', squeeze(app.im(a+2,row,:,:)));
                    set(app.im28, 'CData', squeeze(app.im(a+3,row,:,:)));
                    set(app.im29, 'CData', squeeze(app.im(a+4,row,:,:)));

                end

            else

                set(app.im21, 'CData', zeros(app.dimx,app.dimy));
                set(app.im22, 'CData', zeros(app.dimx,app.dimy));
                set(app.im23, 'CData', zeros(app.dimx,app.dimy));
                set(app.im24, 'CData', zeros(app.dimx,app.dimy));
                set(app.im25, 'CData', zeros(app.dimx,app.dimy));
                set(app.im26, 'CData', zeros(app.dimx,app.dimy));
                set(app.im27, 'CData', zeros(app.dimx,app.dimy));
                set(app.im28, 'CData', zeros(app.dimx,app.dimy));
                set(app.im29, 'CData', zeros(app.dimx,app.dimy));

            end

        end
        
        
        function PlotRow3(app,a,c,x1,x2,y1,y2,flag)
            
            if c > 0
                
                row = c;
                
                if flag == 0
                    
                    minim = app.minEditField.Value;
                    maxim = app.maxEditField.Value;

                    hold(app.MovieFig_31,'on');
                    app.im31 = imshow(squeeze(app.im(a-4,row,:,:)),[minim maxim],'Parent',app.MovieFig_31);
                    app.vertline(3) = plot(app.MovieFig_31,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                    app.horline(3) = plot(app.MovieFig_31,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                    xlim(app.MovieFig_31, [x1 x2]);  ylim(app.MovieFig_31, [y1 y2]);
                    hold(app.MovieFig_31,'off');
                    
                    hold(app.MovieFig_32,'on');
                    app.im32 = imshow(squeeze(app.im(a-3,row,:,:)),[minim maxim],'Parent',app.MovieFig_32);
                    app.diagline(3) = plot(app.MovieFig_32,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                    xlim(app.MovieFig_32, [x1 x2]);  ylim(app.MovieFig_32, [y1 y2]);
                    hold(app.MovieFig_32,'off');
    
                    app.im33 = imshow(squeeze(app.im(a-2,row,:,:)),[minim maxim],'Parent',app.MovieFig_33);
                    app.im34 = imshow(squeeze(app.im(a-1,row,:,:)),[minim maxim],'Parent',app.MovieFig_34);
                    app.im35 = imshow(squeeze(app.im(a  ,row,:,:)),[minim maxim],'Parent',app.MovieFig_35);
                    app.im36 = imshow(squeeze(app.im(a+1,row,:,:)),[minim maxim],'Parent',app.MovieFig_36);
                    app.im37 = imshow(squeeze(app.im(a+2,row,:,:)),[minim maxim],'Parent',app.MovieFig_37);
                    app.im38 = imshow(squeeze(app.im(a+3,row,:,:)),[minim maxim],'Parent',app.MovieFig_38);
                    app.im39 = imshow(squeeze(app.im(a+4,row,:,:)),[minim maxim],'Parent',app.MovieFig_39);
                    
                    xlim(app.MovieFig_31, [x1 x2]);  ylim(app.MovieFig_31, [y1 y2]);
                    xlim(app.MovieFig_32, [x1 x2]);  ylim(app.MovieFig_32, [y1 y2]);
                    xlim(app.MovieFig_33, [x1 x2]);  ylim(app.MovieFig_33, [y1 y2]);
                    xlim(app.MovieFig_34, [x1 x2]);  ylim(app.MovieFig_34, [y1 y2]);
                    xlim(app.MovieFig_35, [x1 x2]);  ylim(app.MovieFig_35, [y1 y2]);
                    xlim(app.MovieFig_36, [x1 x2]);  ylim(app.MovieFig_36, [y1 y2]);
                    xlim(app.MovieFig_37, [x1 x2]);  ylim(app.MovieFig_37, [y1 y2]);
                    xlim(app.MovieFig_38, [x1 x2]);  ylim(app.MovieFig_38, [y1 y2]);
                    xlim(app.MovieFig_39, [x1 x2]);  ylim(app.MovieFig_39, [y1 y2]);
                    
                    drawnow;
                    
                else
                    
                    set(app.im31, 'CData', squeeze(app.im(a-4,row,:,:)));
                    set(app.im32, 'CData', squeeze(app.im(a-3,row,:,:)));
                    set(app.im33, 'CData', squeeze(app.im(a-2,row,:,:)));
                    set(app.im34, 'CData', squeeze(app.im(a-1,row,:,:)));
                    set(app.im35, 'CData', squeeze(app.im(a  ,row,:,:)));
                    set(app.im36, 'CData', squeeze(app.im(a+1,row,:,:)));
                    set(app.im37, 'CData', squeeze(app.im(a+2,row,:,:)));
                    set(app.im38, 'CData', squeeze(app.im(a+3,row,:,:)));
                    set(app.im39, 'CData', squeeze(app.im(a+4,row,:,:)));

                end

            else

                set(app.im31, 'CData', zeros(app.dimx,app.dimy));
                set(app.im32, 'CData', zeros(app.dimx,app.dimy));
                set(app.im33, 'CData', zeros(app.dimx,app.dimy));
                set(app.im34, 'CData', zeros(app.dimx,app.dimy));
                set(app.im35, 'CData', zeros(app.dimx,app.dimy));
                set(app.im36, 'CData', zeros(app.dimx,app.dimy));
                set(app.im37, 'CData', zeros(app.dimx,app.dimy));
                set(app.im38, 'CData', zeros(app.dimx,app.dimy));
                set(app.im39, 'CData', zeros(app.dimx,app.dimy));

            end

        end


        function PlotRow4(app,a,c,x1,x2,y1,y2,flag)
            
            if c > 0
                
                row = c;
                
                if flag == 0
                    
                    minim = app.minEditField.Value;
                    maxim = app.maxEditField.Value;
                    
                    hold(app.MovieFig_41,'on');
                    app.im41 = imshow(squeeze(app.im(a-4,row,:,:)),[minim maxim],'Parent',app.MovieFig_41);
                    app.vertline(4) = plot(app.MovieFig_41,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                    app.horline(4) = plot(app.MovieFig_41,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                    xlim(app.MovieFig_41, [x1 x2]);  ylim(app.MovieFig_41, [y1 y2]);
                    hold(app.MovieFig_41,'off');
                    
                    hold(app.MovieFig_42,'on');
                    app.im42 = imshow(squeeze(app.im(a-3,row,:,:)),[minim maxim],'Parent',app.MovieFig_42);
                    app.diagline(4) = plot(app.MovieFig_42,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                    xlim(app.MovieFig_42, [x1 x2]);  ylim(app.MovieFig_42, [y1 y2]);
                    hold(app.MovieFig_42,'off');

                    app.im43 = imshow(squeeze(app.im(a-2,row,:,:)),[minim maxim],'Parent',app.MovieFig_43);
                    app.im44 = imshow(squeeze(app.im(a-1,row,:,:)),[minim maxim],'Parent',app.MovieFig_44);
                    app.im45 = imshow(squeeze(app.im(a  ,row,:,:)),[minim maxim],'Parent',app.MovieFig_45);
                    app.im46 = imshow(squeeze(app.im(a+1,row,:,:)),[minim maxim],'Parent',app.MovieFig_46);
                    app.im47 = imshow(squeeze(app.im(a+2,row,:,:)),[minim maxim],'Parent',app.MovieFig_47);
                    app.im48 = imshow(squeeze(app.im(a+3,row,:,:)),[minim maxim],'Parent',app.MovieFig_48);
                    app.im49 = imshow(squeeze(app.im(a+4,row,:,:)),[minim maxim],'Parent',app.MovieFig_49);
                    
                    xlim(app.MovieFig_41, [x1 x2]);  ylim(app.MovieFig_41, [y1 y2]);
                    xlim(app.MovieFig_42, [x1 x2]);  ylim(app.MovieFig_42, [y1 y2]);
                    xlim(app.MovieFig_43, [x1 x2]);  ylim(app.MovieFig_43, [y1 y2]);
                    xlim(app.MovieFig_44, [x1 x2]);  ylim(app.MovieFig_44, [y1 y2]);
                    xlim(app.MovieFig_45, [x1 x2]);  ylim(app.MovieFig_45, [y1 y2]);
                    xlim(app.MovieFig_46, [x1 x2]);  ylim(app.MovieFig_46, [y1 y2]);
                    xlim(app.MovieFig_47, [x1 x2]);  ylim(app.MovieFig_47, [y1 y2]);
                    xlim(app.MovieFig_48, [x1 x2]);  ylim(app.MovieFig_48, [y1 y2]);
                    xlim(app.MovieFig_49, [x1 x2]);  ylim(app.MovieFig_49, [y1 y2]);
                    
                    drawnow;
                    
                else
                    
                    set(app.im41, 'CData', squeeze(app.im(a-4,row,:,:)));
                    set(app.im42, 'CData', squeeze(app.im(a-3,row,:,:)));
                    set(app.im43, 'CData', squeeze(app.im(a-2,row,:,:)));
                    set(app.im44, 'CData', squeeze(app.im(a-1,row,:,:)));
                    set(app.im45, 'CData', squeeze(app.im(a  ,row,:,:)));
                    set(app.im46, 'CData', squeeze(app.im(a+1,row,:,:)));
                    set(app.im47, 'CData', squeeze(app.im(a+2,row,:,:)));
                    set(app.im48, 'CData', squeeze(app.im(a+3,row,:,:)));
                    set(app.im49, 'CData', squeeze(app.im(a+4,row,:,:)));

                end

            else

                set(app.im41, 'CData', zeros(app.dimx,app.dimy));
                set(app.im42, 'CData', zeros(app.dimx,app.dimy));
                set(app.im43, 'CData', zeros(app.dimx,app.dimy));
                set(app.im44, 'CData', zeros(app.dimx,app.dimy));
                set(app.im45, 'CData', zeros(app.dimx,app.dimy));
                set(app.im46, 'CData', zeros(app.dimx,app.dimy));
                set(app.im47, 'CData', zeros(app.dimx,app.dimy));
                set(app.im48, 'CData', zeros(app.dimx,app.dimy));
                set(app.im49, 'CData', zeros(app.dimx,app.dimy));

            end

        end

        
        function PlotRow5(app,a,c,x1,x2,y1,y2,flag)
            
            if c > 0
                
                row = c;
                
                 if flag == 0
                     
                     minim = app.minEditField.Value;
                     maxim = app.maxEditField.Value;

                     hold(app.MovieFig_51,'on');
                     app.im51 = imshow(squeeze(app.im(a-4,row,:,:)),[minim maxim],'Parent',app.MovieFig_51);
                     app.vertline(5) = plot(app.MovieFig_51,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                     app.horline(5) = plot(app.MovieFig_51,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                     xlim(app.MovieFig_51, [x1 x2]);  ylim(app.MovieFig_51, [y1 y2]);
                     hold(app.MovieFig_51,'off');

                     hold(app.MovieFig_52,'on');
                     app.im52 = imshow(squeeze(app.im(a-3,row,:,:)),[minim maxim],'Parent',app.MovieFig_52);
                     app.diagline(5) = plot(app.MovieFig_52,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                     xlim(app.MovieFig_52, [x1 x2]);  ylim(app.MovieFig_52, [y1 y2]);
                     hold(app.MovieFig_52,'off');

                     app.im53 = imshow(squeeze(app.im(a-2,row,:,:)),[minim maxim],'Parent',app.MovieFig_53);
                     app.im54 = imshow(squeeze(app.im(a-1,row,:,:)),[minim maxim],'Parent',app.MovieFig_54);
                     app.im55 = imshow(squeeze(app.im(a  ,row,:,:)),[minim maxim],'Parent',app.MovieFig_55);
                     app.im56 = imshow(squeeze(app.im(a+1,row,:,:)),[minim maxim],'Parent',app.MovieFig_56);
                     app.im57 = imshow(squeeze(app.im(a+2,row,:,:)),[minim maxim],'Parent',app.MovieFig_57);
                     app.im58 = imshow(squeeze(app.im(a+3,row,:,:)),[minim maxim],'Parent',app.MovieFig_58);
                     app.im59 = imshow(squeeze(app.im(a+4,row,:,:)),[minim maxim],'Parent',app.MovieFig_59);
                     
                     xlim(app.MovieFig_51, [x1 x2]);  ylim(app.MovieFig_51, [y1 y2]);
                     xlim(app.MovieFig_52, [x1 x2]);  ylim(app.MovieFig_52, [y1 y2]);
                     xlim(app.MovieFig_53, [x1 x2]);  ylim(app.MovieFig_53, [y1 y2]);
                     xlim(app.MovieFig_54, [x1 x2]);  ylim(app.MovieFig_54, [y1 y2]);
                     xlim(app.MovieFig_55, [x1 x2]);  ylim(app.MovieFig_55, [y1 y2]);
                     xlim(app.MovieFig_56, [x1 x2]);  ylim(app.MovieFig_56, [y1 y2]);
                     xlim(app.MovieFig_57, [x1 x2]);  ylim(app.MovieFig_57, [y1 y2]);
                     xlim(app.MovieFig_58, [x1 x2]);  ylim(app.MovieFig_58, [y1 y2]);
                     xlim(app.MovieFig_59, [x1 x2]);  ylim(app.MovieFig_59, [y1 y2]);
                     
                     drawnow;
                     
                 else

                     set(app.im51, 'CData', squeeze(app.im(a-4,row,:,:)));
                     set(app.im52, 'CData', squeeze(app.im(a-3,row,:,:)));
                     set(app.im53, 'CData', squeeze(app.im(a-2,row,:,:)));
                     set(app.im54, 'CData', squeeze(app.im(a-1,row,:,:)));
                     set(app.im55, 'CData', squeeze(app.im(a  ,row,:,:)));
                     set(app.im56, 'CData', squeeze(app.im(a+1,row,:,:)));
                     set(app.im57, 'CData', squeeze(app.im(a+2,row,:,:)));
                     set(app.im58, 'CData', squeeze(app.im(a+3,row,:,:)));
                     set(app.im59, 'CData', squeeze(app.im(a+4,row,:,:)));

                 end

            else
                set(app.im51, 'CData', zeros(app.dimx,app.dimy));
                set(app.im52, 'CData', zeros(app.dimx,app.dimy));
                set(app.im53, 'CData', zeros(app.dimx,app.dimy));
                set(app.im54, 'CData', zeros(app.dimx,app.dimy));
                set(app.im55, 'CData', zeros(app.dimx,app.dimy));
                set(app.im56, 'CData', zeros(app.dimx,app.dimy));
                set(app.im57, 'CData', zeros(app.dimx,app.dimy));
                set(app.im58, 'CData', zeros(app.dimx,app.dimy));
                set(app.im59, 'CData', zeros(app.dimx,app.dimy));

            end

        end
  
         
         function PlotRow6(app,a,c,x1,x2,y1,y2,flag)
             
             if c > 0
                 
                 row = c;
                 
                 if flag == 0
                     
                     minim = app.minEditField.Value;
                     maxim = app.maxEditField.Value;
                     
                     hold(app.MovieFig_61,'on');
                     app.im61 = imshow(squeeze(app.im(a-4,row,:,:)),[minim maxim],'Parent',app.MovieFig_61);
                     app.vertline(6) = plot(app.MovieFig_61,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                     app.horline(6) = plot(app.MovieFig_61,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                     xlim(app.MovieFig_61, [x1 x2]);  ylim(app.MovieFig_61, [y1 y2]);
                     hold(app.MovieFig_61,'off');

                     hold(app.MovieFig_62,'on');
                     app.im62 = imshow(squeeze(app.im(a-3,row,:,:)),[minim maxim],'Parent',app.MovieFig_62);
                     app.diagline(6) = plot(app.MovieFig_62,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                     xlim(app.MovieFig_62, [x1 x2]);  ylim(app.MovieFig_62, [y1 y2]);
                     hold(app.MovieFig_62,'off');

                     app.im63 = imshow(squeeze(app.im(a-2,row,:,:)),[minim maxim],'Parent',app.MovieFig_63);
                     app.im64 = imshow(squeeze(app.im(a-1,row,:,:)),[minim maxim],'Parent',app.MovieFig_64);
                     app.im65 = imshow(squeeze(app.im(a  ,row,:,:)),[minim maxim],'Parent',app.MovieFig_65);
                     app.im66 = imshow(squeeze(app.im(a+1,row,:,:)),[minim maxim],'Parent',app.MovieFig_66);
                     app.im67 = imshow(squeeze(app.im(a+2,row,:,:)),[minim maxim],'Parent',app.MovieFig_67);
                     app.im68 = imshow(squeeze(app.im(a+3,row,:,:)),[minim maxim],'Parent',app.MovieFig_68);
                     app.im69 = imshow(squeeze(app.im(a+4,row,:,:)),[minim maxim],'Parent',app.MovieFig_69);
                     
                     xlim(app.MovieFig_61, [x1 x2]);  ylim(app.MovieFig_61, [y1 y2]);
                     xlim(app.MovieFig_62, [x1 x2]);  ylim(app.MovieFig_62, [y1 y2]);
                     xlim(app.MovieFig_63, [x1 x2]);  ylim(app.MovieFig_63, [y1 y2]);
                     xlim(app.MovieFig_64, [x1 x2]);  ylim(app.MovieFig_64, [y1 y2]);
                     xlim(app.MovieFig_65, [x1 x2]);  ylim(app.MovieFig_65, [y1 y2]);
                     xlim(app.MovieFig_66, [x1 x2]);  ylim(app.MovieFig_66, [y1 y2]);
                     xlim(app.MovieFig_67, [x1 x2]);  ylim(app.MovieFig_67, [y1 y2]);
                     xlim(app.MovieFig_68, [x1 x2]);  ylim(app.MovieFig_68, [y1 y2]);
                     xlim(app.MovieFig_69, [x1 x2]);  ylim(app.MovieFig_69, [y1 y2]);
                     
                     drawnow;

                 else

                     set(app.im61, 'CData', squeeze(app.im(a-4,row,:,:)));
                     set(app.im62, 'CData', squeeze(app.im(a-3,row,:,:)));
                     set(app.im63, 'CData', squeeze(app.im(a-2,row,:,:)));
                     set(app.im64, 'CData', squeeze(app.im(a-1,row,:,:)));
                     set(app.im65, 'CData', squeeze(app.im(a  ,row,:,:)));
                     set(app.im66, 'CData', squeeze(app.im(a+1,row,:,:)));
                     set(app.im67, 'CData', squeeze(app.im(a+2,row,:,:)));
                     set(app.im68, 'CData', squeeze(app.im(a+3,row,:,:)));
                     set(app.im69, 'CData', squeeze(app.im(a+4,row,:,:)));

                 end

             else

                 set(app.im61, 'CData', zeros(app.dimx,app.dimy));
                 set(app.im62, 'CData', zeros(app.dimx,app.dimy));
                 set(app.im63, 'CData', zeros(app.dimx,app.dimy));
                 set(app.im64, 'CData', zeros(app.dimx,app.dimy));
                 set(app.im65, 'CData', zeros(app.dimx,app.dimy));
                 set(app.im66, 'CData', zeros(app.dimx,app.dimy));
                 set(app.im67, 'CData', zeros(app.dimx,app.dimy));
                 set(app.im68, 'CData', zeros(app.dimx,app.dimy));
                 set(app.im69, 'CData', zeros(app.dimx,app.dimy));

             end

         end
         
         
         function SetRows(app)
             
             app.SliceListViewField1.Value = app.center_row(1);
             app.SliceListViewField2.Value = app.center_row(2);
             app.SliceListViewField3.Value = app.center_row(3);
             app.SliceListViewField4.Value = app.center_row(4);
             app.SliceListViewField5.Value = app.center_row(5);
             app.SliceListViewField6.Value = app.center_row(6);
             
         end
         
             
         function SetColumns(app)
          
            app.S1EditField.Value = app.center_column(1);
            app.S2EditField.Value = app.center_column(2);
            app.S3EditField.Value = app.center_column(3);
            app.S4EditField.Value = app.center_column(4);
            app.S5EditField.Value = app.center_column(5);
            app.S6EditField.Value = app.center_column(6);
             
         end
         
         
         function PlotPhysioFigs(app)
             
             cla(app.PhysioFig1);
             cla(app.PhysioFig2);
             cla(app.PhysioFig3);
             cla(app.PhysioFig4);
             cla(app.PhysioFig5);
             app.TabGroup1.SelectedTab = app.Navigators1Tab;

             xcenter = round(app.timeframesEditField.Value/2);
             
             % Plot physiofig 1
             hold(app.PhysioFig1,'on');
             
             for i=1:app.dimz
                 
                 y = squeeze(app.meansignals(i,:))';
                 x = linspace(1,length(y),length(y))';
                 y = y + 100 - i * (200/app.dimz);
                 x = x + round(app.nr_frames/2) - app.column_list(127+i);
                 if mod(i,2)==0 
                     color = app.blue; 
                 else 
                     color = app.green; 
                 end
                 plot(app.PhysioFig1,x,y,'LineWidth',2,'Color',color);
                 
             end
             
             axis(app.PhysioFig1,[0 app.nr_frames+1 -120 110]);
             plot(app.PhysioFig1,[xcenter xcenter],[-110 110],'LineWidth',3,'Color',app.red);
             hold(app.PhysioFig1,'off');
             drawnow;
             
             
             % Plot physiofig 2
             hold(app.PhysioFig2,'on');
             
             for i=1:app.dimz
                 
                 y = squeeze(app.vertsignals(i,:))';
                 x = linspace(1,length(y),length(y))';
                 y = y + 100 - i * (200/app.dimz);
                 x = x + round(app.nr_frames/2) - app.column_list(127+i);
                 if mod(i,2)==0 
                     color = app.blue; 
                 else 
                     color = app.green; 
                 end
                 plot(app.PhysioFig2,x,y,'LineWidth',2,'Color',color);
                 
             end
             
             axis(app.PhysioFig2,[0 app.nr_frames+1 -120 110]);
             plot(app.PhysioFig2,[xcenter xcenter],[-110 110],'LineWidth',3,'Color',app.red);
             hold(app.PhysioFig2,'off');
             drawnow;
             
             % Plot physiofig 3
             hold(app.PhysioFig3,'on');
             
             for i=1:app.dimz
                 
                 y = squeeze(app.horsignals(i,:))';
                 x = linspace(1,length(y),length(y))';
                 y = y + 100 - i * (200/app.dimz);
                 x = x + round(app.nr_frames/2) - app.column_list(127+i);
                 if mod(i,2)==0 
                     color = app.blue; 
                 else 
                     color = app.green; 
                 end
                 plot(app.PhysioFig3,x,y,'LineWidth',2,'Color',color);
                 
             end
             
             axis(app.PhysioFig3,[0 app.nr_frames+1 -120 110]);
             plot(app.PhysioFig3,[xcenter xcenter],[-110 110],'LineWidth',3,'Color',app.red);
             hold(app.PhysioFig3,'off');
             drawnow;
             
             % Plot physiofig 4 = line segment
             hold(app.PhysioFig4,'on');
             
             for i=1:app.dimz
            
                 y = app.diagsignals(i,:)';
                 x = linspace(1,length(y),length(y))';
                 y = y + 100 - i * (200/app.dimz);
                 x = x + round(app.nr_frames/2) - app.column_list(127+i);
                 if mod(i,2)==0 
                     color = app.blue; 
                 else 
                     color = app.green; 
                 end
                 yy = smoothdata(y,"movmean",20);   
                 plot(app.PhysioFig4,x,yy,'LineWidth',2,'Color',color);

                 % Plot peaks
                 [pks,locs] = findpeaks(yy,x,'MinPeakProminence',1);
                 plot(app.PhysioFig4,locs,pks,'o','MarkerSize',8,'MarkerEdgeColor',app.red,'MarkerFaceColor',app.red); 

                 % Plot vallyes
                 [pks,locs] = findpeaks(-yy,x,'MinPeakProminence',1);
                 plot(app.PhysioFig4,locs,-pks,'o','MarkerSize',8,'MarkerEdgeColor',app.orange,'MarkerFaceColor',app.orange); 
     
             end
             
             axis(app.PhysioFig4,[0 app.nr_frames+1 -120 110]);
             plot(app.PhysioFig4,[xcenter xcenter],[-120 110],':','LineWidth',3,'Color',app.red);
             hold(app.PhysioFig4,'off');
             drawnow;
             
         end


    end % Private methods


    % --------------------------------------------------------------
    %
    %                       PUBLIC METHODS
    %
    % --------------------------------------------------------------
    
    methods (Access = public)
        
        
        % Text messages in the app
        function TextMessage(app, message)
            
            app.MessageBox.Value = [message ; app.MessageBox.Value];
            drawnow;
            
        end % TextMessage
        
        
        % Status icon
        function SetStatus(app,status)
            
            switch status
                
                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.statusLabel.Text = 'OK';
                    app.statusLabel.FontColor = app.green;
                    
                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.statusLabel.Text = 'warning';
                    app.statusLabel.FontColor = app.orange;
                    
                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.statusLabel.Text = 'error';
                    app.statusLabel.FontColor = app.brightred;
                    
                case 3
                    app.StatusImage.ImageSource = 'statuswait.gif';
                    app.statusLabel.Text = 'busy';
                    app.statusLabel.FontColor = 'black';
                    
            end
            
            drawnow;
            
        end
        
    end


    % --------------------------------------------------------------
    %
    %                   USER INTERFACE CODE
    %
    % --------------------------------------------------------------
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % disable unnecessary warnings
            warning off;
            clc;

            theme(app.UIFigure,"light");
            
            % Add subfolders to search path
            if (~isdeployed)
                app.olddir = pwd;
                info = dir(which('RealTimeCine.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
            end
            
            % Load all icons & colors
            app.SetAppLayoutProperties;
            app.ColorsButtonGroupSelectionChanged;

            % Check version
            app.CheckVersionFnc;
            
        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)
           
            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                case "classic"
                    guiColors = { ...
                        app.guiGrey, ...                % backgroundColor
                        app.guiBlack, ...               % fontColor
                        app.guiDarkGrey, ...            % boxColor    
                        app.guiWhite, ...               % figureColor    
                        };
                   
                case "black/white"
                    guiColors = { ...
                        app.guiBlack, ...               % backgroundColor
                        app.guiWhite, ...               % fontColor
                        app.guiDarkGrey, ...            % boxColor
                        app.guiGrey, ...                % figureColor 
                        };

            end

            app.SetAppColorsFcn(guiColors);
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            % Buttons
            app.Lamp1.Color = app.red;
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            app.SetStatus(3);
            
            % Ask for dicom import directory
            app.valid_data = false;
            f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
            app.dicom_import_path = uigetdir(app.dicom_import_path,'Dicom import path');
            delete(f);
            figure(app.UIFigure);  % Put the app back in the foreground
            drawnow;

            try
                
                % List of dicom file names
                flist = dir(fullfile(app.dicom_import_path,'*.dcm'));

                % Read dicom info & show in app
                app.TextMessage('Reading Dicom tags ...');
                dcm_info = dicm_hdr([app.dicom_import_path,filesep,flist(1).name]);
                dcm_info = struct2table(dcm_info,'AsArray',true);
                tmpDicomInfoFile = strcat(tempdir,filesep,"dicominfo.txt");
                writetable(rows2vars(dcm_info),tmpDicomInfoFile,'Delimiter','\t');
                dcm_info = fileread(tmpDicomInfoFile);
                app.DCMInfo.Value = string(dcm_info); 
                app.DicomdirectoryEditField.Value = app.dicom_import_path;

                % Read the images
                app.TextMessage('Reading Dicom data ...');
                [~,app.spatial,~] = dicomreadVolume(app.dicom_import_path);
                flist = dir(fullfile(app.dicom_import_path,'*.dcm'));
                app.img = [];
                for i = 1:length(flist)
                    app.img(:,:,i) = dicomread([app.dicom_import_path,filesep,flist(i).name]);
                end
                app.valid_load = true;

            catch
                
                 app.TextMessage('ERROR: Data could not be loaded ...');
                 app.valid_load = false;
                 app.SetStatus(2);
                
            end
            
            % Parameters
            if app.valid_load
                app.TextMessage('Setting image parameters ...');
                app.ImageParameters;
            end

            % Plot images
            if app.valid_data
                app.TextMessage('Plotting all images ...');
                app.PlotAllRows(0);
                app.Lamp1.Color = app.green;
                app.SetStatus(0);
            else
                app.TextMessage('ERROR: Data could not be interpreted ...');
                app.dicom_import_path = [];
                app.valid_load = false;
                app.SetStatus(2);
            end
            
        end

        % Button pushed function: LoadMOLLIButton
        function LoadMOLLIButtonPushed(app, event)
            
            % Buttons
            app.Lamp5.Color = app.red;
            app.Lamp6.Color = app.red;
            app.SetStatus(3);
            
            % Ask for dicom import directory
            app.molli_valid_data = false;
            app.UIFigure.Visible = 'off';
            app.molli_dicom_import_path = uigetdir(app.dicom_import_path,'Dicom import path');
            app.UIFigure.Visible = 'on';
            drawnow;
            
            try
                
                % List of molli dicom file names
                flist = dir(fullfile(app.molli_dicom_import_path,'*.dcm'));

                % Philips dicom dictionary
                dict = dicm_dict('Philips'); %#ok<NASGU> 
                
                % Read dicom info & show in app
                app.TextMessage('Reading Dicom tags ...');
                molli_dcm_info = dicm_hdr([app.molli_dicom_import_path,filesep,flist(1).name]);
                molli_dcm_info = struct2table(molli_dcm_info,'AsArray',true);
                tmpMolliInfoFile = strcat(tempdir,filesep,"mollidcminfo.txt");
                writetable(rows2vars(molli_dcm_info),tmpMolliInfoFile,'Delimiter','\t');
                molli_dcm_info = fileread(tmpMolliInfoFile);
                app.MOLLIDCMInfo.Value = string(molli_dcm_info);
                app.MOLLIDCMdirectoryEditField.Value = app.molli_dicom_import_path;

                % Read the images
                app.TextMessage('Reading Dicom data ...');
                
                for i = 1:length(flist)
                    app.molli_img{i} = dicomread([app.molli_dicom_import_path,filesep,flist(i).name]);
                    app.molli_info{i} = dicominfo([app.molli_dicom_import_path,filesep,flist(i).name]);
                end
                app.molli_valid_load = true;
                
            catch
                
                app.TextMessage('ERROR: Data could not be loaded ...');
                app.molli_valid_load = false;
                app.SetStatus(2);
                
            end
            
            % Check data
            if app.molli_valid_load
                app.TextMessage('Checking image parameters ...');
                app.MolliImageParameters;
            end
            
            
            % Valid data true or false
            if app.molli_valid_data
                app.Lamp5.Color = app.green;
                app.SetStatus(0);
            else
                app.TextMessage('ERROR: Data could not be interpreted ...');
                app.molli_valid_load = false;
                app.SetStatus(2);
            end
            
        end

        % Button pushed function: ExportMOLLIButton
        function ExportMOLLIButtonPushed(app, event)

            % Buttons
            app.Lamp6.Color = app.red;
            app.SetStatus(3);
            ready = false;
            
            if app.molli_valid_data

                % Create folder if not exist, and clear
                try
                    folder_name = [app.molli_dicom_import_path,'_CORRECTED'];
                    if (~exist(folder_name, 'dir'))
                        mkdir(folder_name);
                    end
                    delete([folder_name,filesep,'*']);
                    ready = true;
                catch
                    app.TextMessage('ERROR: directory not writeable ...');
                    app.SetStatus(2);
                end

                if ready

                    try

                        % List of molli dicom file names
                        flist = dir(fullfile(app.molli_dicom_import_path,'*.dcm'));

                        % Philips dicom dictionary
                        dict = dicm_dict('Philips'); %#ok<NASGU> 

                        % Read old and write new files
                        app.TextMessage('Exporting corrected dicom files ...');
                        for i = 1:length(flist)

                            % Read one dcm image and info
                            image = dicomread([app.molli_dicom_import_path,filesep,flist(i).name]);
                            info = dicm_hdr([app.molli_dicom_import_path,filesep,flist(i).name]);

                            % Correct the inversion time
                            info.InversionDelayTime = info.InversionDelayTime + info.TriggerTime - app.mintriggertime;
                            info.TriggerTime = app.mintriggertime;

                            % Write the new file
                            dicm_save(image, [folder_name,filesep,flist(i).name], info);

                        end

                    catch

                        ready = false;

                    end

                end
                    
            end
            
            
            if ready
                
                app.TextMessage('Dicom file export completed ...');
                app.Lamp6.Color = app.green;
                app.SetStatus(0);
                
            else
                
                app.TextMessage('ERROR: something went wrong during dicom export ...');
                app.Lamp6.Color = app.red;
                app.SetStatus(2);
                
            end
         
        end

        % Value changed function: maxEditField, minEditField, x1EditField, 
        % ...and 3 other components
        function DimensionsChanged(app, event)
            
            if app.valid_data
                
                app.SetStatus(3);
                
                app.TextMessage('Replotting all images ...');
                app.PlotAllRows(0);
                drawnow;
                
                app.SetStatus(0);
                
            end
            
        end

        % Button pushed function: Right1
        function Right1ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(1) = app.center_column(1) + 1;
                
                if app.center_column(1) > app.nr_frames - 4
                    app.center_column(1) = app.nr_frames - 4;
                end
                
                app.S1EditField.Value = app.center_column(1);
                
                app.column_list(app.row_cnt) = app.center_column(1);
                
                app.PlotRow1(app.center_column(1),app.center_row(1),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Button pushed function: Left1
        function Left1ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(1) = app.center_column(1) - 1;
                
                if app.center_column(1) < 5
                    app.center_column(1) = 5;
                end
                
                app.S1EditField.Value = app.center_column(1);
                
                app.column_list(app.row_cnt) = app.center_column(1);
                
                app.PlotRow1(app.center_column(1),app.center_row(1),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Button pushed function: Right2
        function Right2ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(2) = app.center_column(2) + 1;
                
                if app.center_column(2) > app.nr_frames - 4
                    app.center_column(2) = app.nr_frames - 4;
                end
                
                app.S2EditField.Value = app.center_column(2);
                
                app.column_list(app.row_cnt+1) = app.center_column(2);
                
                app.PlotRow2(app.center_column(2),app.center_row(2),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Button pushed function: Left2
        function Left2ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(2) = app.center_column(2) - 1;
                
                if app.center_column(2) < 5
                    app.center_column(2) = 5;
                end
                
                app.S2EditField.Value = app.center_column(2);
                
                app.column_list(app.row_cnt+1) = app.center_column(2);
                
                app.PlotRow2(app.center_column(2),app.center_row(2),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Button pushed function: Right3
        function Right3ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(3) = app.center_column(3) + 1;
                
                if app.center_column(3) > app.nr_frames - 4
                    app.center_column(3) = app.nr_frames - 4;
                end
                
                app.S3EditField.Value = app.center_column(3);
                
                app.column_list(app.row_cnt+2) = app.center_column(3);
                
                app.PlotRow3(app.center_column(3),app.center_row(3),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Button pushed function: Left3
        function Left3ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(3) = app.center_column(3) - 1;
                
                if app.center_column(3) < 5
                    app.center_column(3) = 5;
                end
                
                app.S3EditField.Value = app.center_column(3);
                
                app.column_list(app.row_cnt+2) = app.center_column(3);
                
                app.PlotRow3(app.center_column(3),app.center_row(3),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Button pushed function: Right4
        function Right4ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(4) = app.center_column(4) + 1;
                
                if app.center_column(4) > app.nr_frames - 4
                    app.center_column(4) = app.nr_frames - 4;
                end
                
                app.S4EditField.Value = app.center_column(4);
                
                app.column_list(app.row_cnt+3) = app.center_column(4);
                
                app.PlotRow4(app.center_column(4),app.center_row(4),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Button pushed function: Left4
        function Left4ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(4) = app.center_column(4) - 1;
                
                if app.center_column(4) < 5
                    app.center_column(4) = 5;
                end
                
                app.S4EditField.Value = app.center_column(4);
                
                app.column_list(app.row_cnt+3) = app.center_column(4);
                
                app.PlotRow4(app.center_column(4),app.center_row(4),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Button pushed function: Right5
        function Right5ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(5) = app.center_column(5) + 1;
                
                if app.center_column(5) > app.nr_frames - 4
                    app.center_column(5) = app.nr_frames - 4;
                end
                
                app.S5EditField.Value = app.center_column(5);
                
                app.column_list(app.row_cnt+4) = app.center_column(5);
                
                app.PlotRow5(app.center_column(5),app.center_row(5),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Button pushed function: Left5
        function Left5ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(5) = app.center_column(5) - 1;
                
                if app.center_column(5) < 5
                    app.center_column(5) = 5;
                end
                
                app.S5EditField.Value = app.center_column(5);
                
                app.column_list(app.row_cnt+4) = app.center_column(5);
                
                app.PlotRow5(app.center_column(5),app.center_row(5),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Button pushed function: Right6
        function Right6ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(6) = app.center_column(6) + 1;
                
                if app.center_column(6) > app.nr_frames - 4
                    app.center_column(6) = app.nr_frames - 4;
                end
                
                app.S6EditField.Value = app.center_column(6);
                
                app.column_list(app.row_cnt+5) = app.center_column(6);
                
                app.PlotRow6(app.center_column(6),app.center_row(6),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Button pushed function: Left6
        function Left6ButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(6) = app.center_column(6) - 1;
                
                if app.center_column(6)  < 5
                    app.center_column(6) = 5;
                end
                
                app.S6EditField.Value = app.center_column(6);
                
                app.column_list(app.row_cnt+5) = app.center_column(6);
                
                app.PlotRow6(app.center_column(6),app.center_row(6),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                
                drawnow;
                
            end
            
        end

        % Value changed function: S1EditField
        function S1EditFieldValueChanged(app, event)
           
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(1) = app.S1EditField.Value;
                
                if app.center_column(1) > app.nr_frames - 4
                    app.center_column(1) = app.nr_frames - 4;
                end
                
                if app.center_column(1) < 5
                    app.center_column(1) = 5;
                end
                
                app.S1EditField.Value = app.center_column(1);
                
                app.column_list(app.row_cnt) = app.center_column(1);
                
                app.PlotRow1(app.center_column(1),app.center_row(1),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                drawnow;
                
            end
            
        end

        % Value changed function: S2EditField
        function S2EditFieldValueChanged(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(2) = app.S2EditField.Value;
                
                if app.center_column(2) > app.nr_frames - 4
                    app.center_column(2) = app.nr_frames - 4;
                end
                
                if app.center_column(2) < 5
                    app.center_column(2) = 5;
                end
                
                app.S2EditField.Value = app.center_column(2);
                
                app.column_list(app.row_cnt+1) = app.center_column(2);
                
                app.PlotRow2(app.center_column(2),app.center_row(2),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                drawnow;
                
            end
            
        end

        % Value changed function: S3EditField
        function S3EditFieldValueChanged(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(3) = app.S3EditField.Value;
                
                if app.center_column(3) > app.nr_frames - 4
                    app.center_column(3) = app.nr_frames - 4;
                end
                
                if app.center_column(3) < 5
                    app.center_column(3) = 5;
                end
                
                app.S3EditField.Value = app.center_column(3);
                
                app.column_list(app.row_cnt+2) = app.center_column(3);
                
                app.PlotRow3(app.center_column(3),app.center_row(3),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                drawnow;
                
            end
            
        end

        % Value changed function: S4EditField
        function S4EditFieldValueChanged(app, event)
           
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(4) = app.S4EditField.Value;
                
                if app.center_column(4) > app.nr_frames - 4
                    app.center_column(4) = app.nr_frames - 4;
                end
                
                if app.center_column(4) < 5
                    app.center_column(4) = 5;
                end
                
                app.S4EditField.Value = app.center_column(4);
                
                app.column_list(app.row_cnt+3) = app.center_column(4);
                
                app.PlotRow4(app.center_column(4),app.center_row(4),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                drawnow;
                
            end
            
        end

        % Value changed function: S5EditField
        function S5EditFieldValueChanged(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(5) = app.S5EditField.Value;
                
                if app.center_column(5) > app.nr_frames - 4
                    app.center_column(5) = app.nr_frames - 4;
                end
                
                if app.center_column(5) < 5
                    app.center_column(5) = 5;
                end
                
                app.S5EditField.Value = app.center_column(5);
                
                app.column_list(app.row_cnt+4) = app.center_column(5);
                
                app.PlotRow5(app.center_column(5),app.center_row(5),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                drawnow;
                
            end
            
        end

        % Value changed function: S6EditField
        function S6EditFieldValueChanged(app, event)
            
            app.Lamp2.Color = app.red;
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            
            if app.valid_data
                
                app.center_column(6) = app.S6EditField.Value;
                
                if app.center_column(6) > app.nr_frames - 4
                    app.center_column(6) = app.nr_frames - 4;
                end
                
                if app.center_column(6) < 5
                    app.center_column(6) = 5;
                end
                
                app.S6EditField.Value = app.center_column(6);
                
                app.column_list(app.row_cnt+5) = app.center_column(6);
                
                app.PlotRow6(app.center_column(6),app.center_row(6),app.x1EditField.Value,app.x2EditField.Value,app.y1EditField.Value,app.y2EditField.Value,1);
                drawnow;
                
            end
            
        end

        % Button pushed function: SliceUp
        function SliceUpButtonPushed(app, event)
            
            app.row_cnt = app.row_cnt - 1;
            if app.row_cnt < 1 
                app.row_cnt = 1;
            end
            
            app.center_row(1:6) = app.row_list(app.row_cnt:app.row_cnt+5);
            app.center_column(1:6) = app.column_list(app.row_cnt:app.row_cnt+5);
            
            app.SetRows;
            app.SetColumns;
            
            app.PlotAllRows(1);
            
        end

        % Button pushed function: SliceDown
        function SliceDownButtonPushed(app, event)
             
            app.row_cnt = app.row_cnt + 1;
            if app.row_cnt > 250
                app.row_cnt = 250;
            end
            
            app.center_row(1:6) = app.row_list(app.row_cnt:app.row_cnt+5);
            app.center_column(1:6) = app.column_list(app.row_cnt:app.row_cnt+5);
            
            app.SetRows;
            app.SetColumns;
            
            app.PlotAllRows(1);   
            
        end

        % Button pushed function: PhysioButton
        function PhysioButtonPushed(app, event)
            
            app.Lamp2.Color = app.red;
            drawnow;
            
            if app.valid_data
                
                app.SetStatus(3);
                app.TextMessage('Calculating physiological traces ...');
                
                % Calculate signals to show
               
                % Means of whole image
                app.meansignals = mean(app.im,[3,4])';
                for i = 1:app.dimz
                    app.meansignals(i,:) = app.meansignals(i,:)-mean(squeeze(app.meansignals(i,:)));
                    app.meansignals(i,:) = app.meansignals(i,:)*10/max(abs(squeeze(app.meansignals(i,:))));
                end
                
                % Vertical line
                app.vertsignals = mean(app.im(:,:,app.TracexEditField.Value,:),4)';
                for i = 1:app.dimz
                    app.vertsignals(i,:) = app.vertsignals(i,:)-mean(squeeze(app.vertsignals(i,:)));
                    app.vertsignals(i,:) = app.vertsignals(i,:)*10/max(abs(squeeze(app.vertsignals(i,:))));
                end
                
                % Horizontal line
                app.horsignals = mean(app.im(:,:,:,app.TraceyEditField.Value),3)';
                for i = 1:app.dimz
                    app.horsignals(i,:) = app.horsignals(i,:)-mean(squeeze(app.horsignals(i,:)));
                    app.horsignals(i,:) = app.horsignals(i,:)*10/max(abs(squeeze(app.horsignals(i,:))));
                end
                
                % Tilted line
                lx = linspace(app.Tracex1EditField.Value,app.Tracex2EditField.Value,300);
                ly = linspace(app.Tracey1EditField.Value,app.Tracey2EditField.Value,300);
                lb = unique(round([lx ; ly]'),'rows');
                tim = zeros(app.nr_frames,app.dimz,length(lb));
                for i = 1:length(lb)
                    tim(:,:,i) = app.im(:,:,lb(i,1),lb(i,2));
                end
                app.diagsignals = mean(tim,3)';
                for i = 1:app.dimz
                    app.diagsignals(i,:) = app.diagsignals(i,:)-mean(squeeze(app.diagsignals(i,:)));
                    app.diagsignals(i,:) = app.diagsignals(i,:)*10/max(abs(squeeze(app.diagsignals(i,:))));
                end
                
                % Replot all traces
                app.PlotPhysioFigs;
                
                app.Lamp2.Color = app.green;
                app.SetStatus(0);
                
            end
            
        end

        % Button pushed function: AlignPeaksButton
        function AlignPeaksButtonPushed(app, event)

            if app.valid_data
                
                app.PhysioButtonPushed;

                target = round(app.timeframesEditField.Value/2);
                
                for i = 1:app.dimz

                    yy = smoothdata(squeeze(app.diagsignals(i,:)),"movmean",20);   
                    [~,locs] = findpeaks(yy,'MinPeakProminence',1);
                
                    [~,idx]=min(abs(locs-target));
                    minVal=locs(idx);

                    minVal(minVal<5) = 5;
                    minVal(minVal>(app.timeframesEditField.Value-5)) = app.timeframesEditField.Value-5;

                    if isempty(minVal)
                        minVal = target;
                    end

                    app.column_list(127+i) = minVal(1);
   
                end

                app.center_column(1) = app.column_list(app.row_cnt);
                app.center_column(2) = app.column_list(app.row_cnt+1);
                app.center_column(3) = app.column_list(app.row_cnt+2);
                app.center_column(4) = app.column_list(app.row_cnt+3);
                app.center_column(5) = app.column_list(app.row_cnt+4);
                app.center_column(6) = app.column_list(app.row_cnt+5);

                app.S1EditField.Value = app.center_column(1);
                app.S2EditField.Value = app.center_column(2);
                app.S3EditField.Value = app.center_column(3);
                app.S4EditField.Value = app.center_column(4);
                app.S5EditField.Value = app.center_column(5);
                app.S6EditField.Value = app.center_column(6);

                app.S1EditFieldValueChanged;
                app.S2EditFieldValueChanged;
                app.S3EditFieldValueChanged;
                app.S4EditFieldValueChanged;
                app.S5EditFieldValueChanged;
                app.S6EditFieldValueChanged;

                app.PhysioButtonPushed;

            end

        end

        % Button pushed function: AlignValleyButton
        function AlignValleyButtonPushed(app, event)
           
            if app.valid_data
                
                app.PhysioButtonPushed;

                target = round(app.timeframesEditField.Value/2);
                
                for i = 1:app.dimz

                    yy = smoothdata(squeeze(app.diagsignals(i,:)),"movmean",20);   
                    [~,locs] = findpeaks(-yy,'MinPeakProminence',1);
                
                    [~,idx]=min(abs(locs-target));
                    minVal=locs(idx);

                    minVal(minVal<5) = 5;
                    minVal(minVal>(app.timeframesEditField.Value-5)) = app.timeframesEditField.Value-5;

                    if isempty(minVal)
                        minVal = target;
                    end

                    app.column_list(127+i) = minVal(1);
   
                end

                app.center_column(1) = app.column_list(app.row_cnt);
                app.center_column(2) = app.column_list(app.row_cnt+1);
                app.center_column(3) = app.column_list(app.row_cnt+2);
                app.center_column(4) = app.column_list(app.row_cnt+3);
                app.center_column(5) = app.column_list(app.row_cnt+4);
                app.center_column(6) = app.column_list(app.row_cnt+5);

                app.S1EditField.Value = app.center_column(1);
                app.S2EditField.Value = app.center_column(2);
                app.S3EditField.Value = app.center_column(3);
                app.S4EditField.Value = app.center_column(4);
                app.S5EditField.Value = app.center_column(5);
                app.S6EditField.Value = app.center_column(6);

                app.S1EditFieldValueChanged;
                app.S2EditFieldValueChanged;
                app.S3EditFieldValueChanged;
                app.S4EditFieldValueChanged;
                app.S5EditFieldValueChanged;
                app.S6EditFieldValueChanged;

                app.PhysioButtonPushed;

            end
        end

        % Button pushed function: LoadSelectButton
        function LoadSelectButtonPushed(app, event)
            
            if app.valid_data
               
                % Ask for key file
                app.UIFigure.Visible = 'off';
                [file,path,~] = uigetfile('*.txt','Key images file');
                app.UIFigure.Visible = 'on';
                drawnow;
                
                try
                    
                    mtx = readmatrix([path,filesep,file]);
                    
                    app.ESV1 = squeeze(mtx(1,:));
                    app.EDV1 = squeeze(mtx(2,:));
                    app.ESV2 = squeeze(mtx(3,:));
                    app.EDV2 = squeeze(mtx(4,:));
                    
                    app.ESV1ViewField.Value = num2str(app.ESV1);
                    app.EDV1ViewField.Value = num2str(app.EDV1);
                    app.ESV2ViewField.Value = num2str(app.ESV2);
                    app.EDV2ViewField.Value = num2str(app.EDV2);
                    
                    app.ESV1Button.BackgroundColor = app.blue;
                    app.EDV1Button.BackgroundColor = app.grey;
                    app.ESV2Button.BackgroundColor = app.grey;
                    app.EDV2Button.BackgroundColor = app.grey;
                   
                    app.column_list(128:128+app.dimz-1) = app.ESV1';
                    app.row_cnt = 128;
                    
                    app.center_row(1:6) = app.row_list(app.row_cnt:app.row_cnt+5);
                    app.center_column(1:6) = app.column_list(app.row_cnt:app.row_cnt+5);
                   
                    app.SetRows;
                    app.SetColumns;
                    
                    app.PlotAllRows(1);
                    
                    app.TextMessage('Imported key images ...');
                    app.SetStatus(0);
                    app.Lamp3.Color = app.green;
                    drawnow;
                    
                catch
                    
                    app.TextMessage('ERROR: not a valid key file ...');
                    app.SetStatus(1);
                    app.Lamp3.Color = app.red;
                    drawnow;
                    
                end
                
            end
            
        end

        % Button pushed function: LoadPPUButton
        function LoadPPUButtonPushed(app, event)
            
            app.TextMessage('PPU trace not implemented yet ...');
            app.SetStatus(1);
            
        end

        % Value changed function: TracexEditField
        function TracexEditFieldValueChanged(app, event)
            
            app.Lamp2.Color = app.red;
            
            if app.valid_data
                
                app.TracexEditField.Value(app.TracexEditField.Value > app.dimx) = app.dimx;
                app.TracexEditField.Value(app.TracexEditField.Value < 1) = 1;
                
                delete(app.vertline);

                hold(app.MovieFig_11,'on');
                app.vertline(1) = plot(app.MovieFig_11,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_11,'off');

                hold(app.MovieFig_21,'on');
                app.vertline(2) = plot(app.MovieFig_21,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_21,'off');

                hold(app.MovieFig_31,'on');
                app.vertline(3) = plot(app.MovieFig_31,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_31,'off');
                
                hold(app.MovieFig_41,'on');
                app.vertline(4) = plot(app.MovieFig_41,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_41,'off');

                hold(app.MovieFig_51,'on');
                app.vertline(5) = plot(app.MovieFig_51,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_51,'off');

                hold(app.MovieFig_61,'on');
                app.vertline(6) = plot(app.MovieFig_61,[app.TracexEditField.Value app.TracexEditField.Value],[1 app.dimy],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_61,'off');

            end
            
        end

        % Value changed function: TraceyEditField
        function TraceyEditFieldValueChanged(app, event)
            
            app.Lamp2.Color = app.red;
            
            if app.valid_data
                
                app.TraceyEditField.Value(app.TraceyEditField.Value > app.dimy) = app.dimy;
                app.TraceyEditField.Value(app.TraceyEditField.Value < 1) = 1;
                
                delete(app.horline);

                hold(app.MovieFig_11,'on');
                app.horline(1) = plot(app.MovieFig_11,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_11,'off');

                hold(app.MovieFig_21,'on');
                app.horline(2) = plot(app.MovieFig_21,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_21,'off');

                hold(app.MovieFig_31,'on');
                app.horline(3) = plot(app.MovieFig_31,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_31,'off');

                hold(app.MovieFig_41,'on');
                app.horline(4) = plot(app.MovieFig_41,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_41,'off');

                hold(app.MovieFig_51,'on');
                app.horline(5) = plot(app.MovieFig_51,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_51,'off');

                hold(app.MovieFig_61,'on');
                app.horline(6) = plot(app.MovieFig_61,[1 app.dimx],[app.TraceyEditField.Value app.TraceyEditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_61,'off');
                
            end
            
        end

        % Value changed function: Tracex1EditField, Tracex2EditField, 
        % ...and 2 other components
        function TraceDiagEditFieldValueChanged(app, event)
            
            app.Lamp2.Color = app.red;
            
            if app.valid_data
                
                app.Tracex1EditField.Value(app.Tracex1EditField.Value > app.dimx) = app.dimx;
                app.Tracex1EditField.Value(app.Tracex1EditField.Value < 1) = 1;
                
                app.Tracex2EditField.Value(app.Tracex2EditField.Value > app.dimx) = app.dimx;
                app.Tracex2EditField.Value(app.Tracex2EditField.Value < 1) = 1;
                
                app.Tracey1EditField.Value(app.Tracey1EditField.Value > app.dimy) = app.dimy;
                app.Tracey1EditField.Value(app.Tracey1EditField.Value < 1) = 1;
                
                app.Tracey2EditField.Value(app.Tracey2EditField.Value > app.dimy) = app.dimy;
                app.Tracey2EditField.Value(app.Tracey2EditField.Value < 1) = 1;
                
                delete(app.diagline);

                hold(app.MovieFig_12,'on');
                app.diagline(1) = plot(app.MovieFig_12,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_12,'off');

                hold(app.MovieFig_22,'on');
                app.diagline(2) = plot(app.MovieFig_22,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_22,'off');

                hold(app.MovieFig_32,'on');
                app.diagline(3) = plot(app.MovieFig_32,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_32,'off');

                hold(app.MovieFig_42,'on');
                app.diagline(4) = plot(app.MovieFig_42,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_42,'off');

                hold(app.MovieFig_52,'on');
                app.diagline(5) = plot(app.MovieFig_52,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_52,'off');

                hold(app.MovieFig_62,'on');
                app.diagline(6) = plot(app.MovieFig_62,[app.Tracex1EditField.Value app.Tracex2EditField.Value],[app.Tracey1EditField.Value app.Tracey2EditField.Value],':','LineWidth',app.linethickness,'Color',app.red);
                hold(app.MovieFig_62,'off');
                
            end
            
        end

        % Button pushed function: ESV1Button
        function ESV1ButtonPushed(app, event)
            
            if app.valid_data
                
                app.ESV1Button.BackgroundColor = app.blue;
                app.EDV1Button.BackgroundColor = app.grey;
                app.ESV2Button.BackgroundColor = app.grey;
                app.EDV2Button.BackgroundColor = app.grey;
                
                app.ESV1 = app.column_list(128:128+app.dimz-1)';
                app.ESV1ViewField.Value = num2str(app.ESV1);
                
            end
            
        end

        % Button pushed function: RButtonESV1
        function RButtonESV1Pushed(app, event)
                        
            if app.valid_data && ~isempty(app.ESV1) 
                
                app.ESV1Button.BackgroundColor = app.blue;
                app.EDV1Button.BackgroundColor = app.grey;
                app.ESV2Button.BackgroundColor = app.grey;
                app.EDV2Button.BackgroundColor = app.grey;
                
                app.column_list(128:128+app.dimz-1) = app.ESV1';
                app.ESV1ViewField.Value = num2str(app.ESV1);
                
                app.center_row(1:6) = app.row_list(app.row_cnt:app.row_cnt+5);
                app.center_column(1:6) = app.column_list(app.row_cnt:app.row_cnt+5);
            
                app.SetRows;
                app.SetColumns;
                
                app.PlotAllRows(1);
                drawnow;
                
            end
            
        end

        % Button pushed function: EDV1Button
        function EDV1ButtonPushed(app, event)
             
            if app.valid_data
                
                app.ESV1Button.BackgroundColor = app.grey;
                app.EDV1Button.BackgroundColor = app.blue;
                app.ESV2Button.BackgroundColor = app.grey;
                app.EDV2Button.BackgroundColor = app.grey;
                
                app.EDV1 = app.column_list(128:128+app.dimz-1)';
                app.EDV1ViewField.Value = num2str(app.EDV1);
                
            end
            
        end

        % Button pushed function: RButtonEDV1
        function RButtonEDV1Pushed(app, event)
                    
            if app.valid_data && ~isempty(app.EDV1) 
                
                app.ESV1Button.BackgroundColor = app.grey;
                app.EDV1Button.BackgroundColor = app.blue;
                app.ESV2Button.BackgroundColor = app.grey;
                app.EDV2Button.BackgroundColor = app.grey;
                
                app.column_list(128:128+app.dimz-1) = app.EDV1';
                app.EDV1ViewField.Value = num2str(app.EDV1);
                
                app.center_row(1:6) = app.row_list(app.row_cnt:app.row_cnt+5);
                app.center_column(1:6) = app.column_list(app.row_cnt:app.row_cnt+5);
            
                app.SetRows;
                app.SetColumns;
                
                app.PlotAllRows(1);
                drawnow;
                
            end
            
        end

        % Button pushed function: ESV2Button
        function ESV2ButtonPushed(app, event)
             
            if app.valid_data
                
                app.ESV1Button.BackgroundColor = app.grey;
                app.EDV1Button.BackgroundColor = app.grey;
                app.ESV2Button.BackgroundColor = app.blue;
                app.EDV2Button.BackgroundColor = app.grey;
                
                app.ESV2 = app.column_list(128:128+app.dimz-1)';
                app.ESV2ViewField.Value = num2str(app.ESV2);
                
            end
            
        end

        % Button pushed function: RButtonESV2
        function RButtonESV2Pushed(app, event)
            
            if app.valid_data && ~isempty(app.ESV2) 
                
                app.ESV1Button.BackgroundColor = app.grey;
                app.EDV1Button.BackgroundColor = app.grey;
                app.ESV2Button.BackgroundColor = app.blue;
                app.EDV2Button.BackgroundColor = app.grey;
                
                app.column_list(128:128+app.dimz-1) = app.ESV2';
                app.ESV2ViewField.Value = num2str(app.ESV2);
                
                app.center_row(1:6) = app.row_list(app.row_cnt:app.row_cnt+5);
                app.center_column(1:6) = app.column_list(app.row_cnt:app.row_cnt+5);
            
                app.SetRows;
                app.SetColumns;
                
                app.PlotAllRows(1);
                drawnow;
                
            end
            
        end

        % Button pushed function: EDV2Button
        function EDV2ButtonPushed(app, event)
             
            if app.valid_data
                
                app.ESV1Button.BackgroundColor = app.grey;
                app.EDV1Button.BackgroundColor = app.grey;
                app.ESV2Button.BackgroundColor = app.grey;
                app.EDV2Button.BackgroundColor = app.blue;
                
                app.EDV2 = app.column_list(128:128+app.dimz-1)';
                app.EDV2ViewField.Value = num2str(app.EDV2);
                
            end
            
        end

        % Button pushed function: RButtonEDV2
        function RButtonEDV2Pushed(app, event)
           
            if app.valid_data && ~isempty(app.EDV2) 
                
                app.ESV1Button.BackgroundColor = app.grey;
                app.EDV1Button.BackgroundColor = app.grey;
                app.ESV2Button.BackgroundColor = app.grey;
                app.EDV2Button.BackgroundColor = app.blue;
                
                app.column_list(128:128+app.dimz-1) = app.EDV2';
                app.EDV2ViewField.Value = num2str(app.EDV2);
                
                app.center_row(1:6) = app.row_list(app.row_cnt:app.row_cnt+5);
                app.center_column(1:6) = app.column_list(app.row_cnt:app.row_cnt+5);
            
                app.SetRows;
                app.SetColumns;
                
                app.PlotAllRows(1);
                drawnow;
                
            end
            
        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)
            
            app.Lamp4.Color = app.red;
            app.TextMessage('Exporting Dicom images ...');
            drawnow;
            
            if app.valid_data && ~isempty(app.ESV1) && ~isempty(app.EDV1) && ~isempty(app.ESV2) && ~isempty(app.EDV2)
                
                % Ask for dicom export directory
                app.UIFigure.Visible = 'off';
                app.dicom_export_path = uigetdir(app.dicom_export_path,'Dicom export path');
                app.UIFigure.Visible = 'on';
                drawnow;
                
                % Check if directory exists and is writeable
                ready = true;
                if ~ischar(app.dicom_export_path)     % user cancel
                    ready = false;
                    app.dicom_export_path = '';
                else
                    [~, Attrib] = fileattrib(app.dicom_export_path);
                    if ~any(Attrib.UserWrite)       % check if directory is writable
                        ready = false;
                        app.TextMessage('ERROR: directory not writeable ...');
                        app.SetStatus(2);
                    end
                end
                
                % create folder if not exist, and clear
                try
                    folder_name = [app.dicom_export_path,filesep,'RTC_Dicom'];
                    if (~exist(folder_name, 'dir')); mkdir(folder_name); end
                    delete([folder_name,filesep,'*']);
                catch
                    ready = false;
                    app.TextMessage('ERROR: directory not writeable ...');
                    app.SetStatus(2);
                end
                
                % List of dicom file names
                flist = dir(fullfile(app.dicom_import_path,'*.dcm'));
                if isempty(flist)
                    ready = false;
                end

                % Pre-allocate images
                % dcm_image = zeros(app.dimz, 4, app.dimx, app.dimy);
                
                if ready
                    
                    % Reading the original images & Dicom tags
                    for i = 1 : app.dimz
                        
                        app.TextMessage(strcat('Reading slice',{' '},num2str(i),{' '},'...'));
                        
                        fnumber1 = app.ESV1(i) + (i-1)*app.nr_frames;
                        fnumber2 = app.EDV1(i) + (i-1)*app.nr_frames;
                        fnumber3 = app.ESV2(i) + (i-1)*app.nr_frames;
                        fnumber4 = app.EDV2(i) + (i-1)*app.nr_frames;
                        
                        % Read image
                        dcm_image(i,1,:,:) = dicomread([app.dicom_import_path,filesep,flist(fnumber1).name]);
                        dcm_image(i,2,:,:) = dicomread([app.dicom_import_path,filesep,flist(fnumber2).name]);
                        dcm_image(i,3,:,:) = dicomread([app.dicom_import_path,filesep,flist(fnumber3).name]);
                        dcm_image(i,4,:,:) = dicomread([app.dicom_import_path,filesep,flist(fnumber4).name]);
                        
                        % Read tags
                        dcm_info(i,1) = dicominfo([app.dicom_import_path,filesep,flist(fnumber1).name]); %#ok<*AGROW> 
                        dcm_info(i,2) = dicominfo([app.dicom_import_path,filesep,flist(fnumber2).name]);
                        dcm_info(i,3) = dicominfo([app.dicom_import_path,filesep,flist(fnumber3).name]);
                        dcm_info(i,4) = dicominfo([app.dicom_import_path,filesep,flist(fnumber4).name]);
                        
                        % Change relevant Dicom tags
                        dcm_info(i,1).TriggerTime = 0;
                        dcm_info(i,2).TriggerTime = 100;
                        dcm_info(i,3).TriggerTime = 200;
                        dcm_info(i,4).TriggerTime = 300;
                        
                        dcm_info(i,1).NumberOfTemporalPositions = 4;
                        dcm_info(i,2).NumberOfTemporalPositions = 4;
                        dcm_info(i,3).NumberOfTemporalPositions = 4;
                        dcm_info(i,4).NumberOfTemporalPositions = 4;
                        
                        dcm_info(i,1).TemporalPositionIdentifier = 1;
                        dcm_info(i,2).TemporalPositionIdentifier = 2;
                        dcm_info(i,3).TemporalPositionIdentifier = 3;
                        dcm_info(i,4).TemporalPositionIdentifier = 4;
                        
                        dcm_info(i,1).InstanceNumber = 1 + (i-1)*4;
                        dcm_info(i,2).InstanceNumber = 2 + (i-1)*4;
                        dcm_info(i,3).InstanceNumber = 3 + (i-1)*4;
                        dcm_info(i,4).InstanceNumber = 4 + (i-1)*4;
                        
                        dcm_info(i,1).StudyComments = num2str(app.ESV1);
                        dcm_info(i,2).StudyComments = num2str(app.EDV1);
                        dcm_info(i,3).StudyComments = num2str(app.ESV2);
                        dcm_info(i,4).StudyComments = num2str(app.EDV2);
                        
                    end
                    
                    
                    try
                         
                        % Exporting the new Dicom images
                        cnt = 1;
                        for i = 1 : app.dimz
                            
                            app.TextMessage(strcat('Exporting slice',{' '},num2str(i),{' '},'...'));
                            
                            for j = 1 : 4
                                
                                nr = ['000000',num2str(cnt)];
                                nr = nr(end-5:end);
                                filename = [folder_name,filesep,'im_',nr,'.dcm'];
                                dicomwrite(squeeze(dcm_image(i,j,:,:)),filename,dcm_info(i,j));
                                cnt = cnt + 1;
                                
                            end
                            
                        end
                        
                        
                        % Export the key images
                        app.TextMessage('Exporting the key images ...');
                        
                        mtx = [app.ESV1 ; app.EDV1 ; app.ESV2 ; app.EDV2];
                        writematrix(mtx,[app.dicom_export_path,filesep,'RTC_Dicom',filesep,'key.txt']);
                                                
                    catch
                        
                        ready = false;
                        
                    end


                end
                
                % Success or not
                if ready
                    
                    app.Lamp4.Color = app.green;
                    app.SetStatus(0);
                    app.TextMessage('Dicom export successful ...');
                    drawnow;
                
                else
                    
                    app.Lamp4.Color = app.red;
                    app.SetStatus(2);
                    app.TextMessage('ERROR: Dicom export failed ...');
                    drawnow;
                    
                end
                
            end
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            key = event.Key;
            
            try

                switch key

                    case 's'

                        % Save the app ui in a png image
                        app.TextMessage('Saving UI ...');
                        exportapp(app.UIFigure,"RTC.png");

                end

            catch ME

                app.TextMessage(ME.message);

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
             
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('RealTimeCine.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
            end
            
            % Exit
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'RTC';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'RTCicon.png');
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup0 = uitabgroup(app.UIFigure);
            app.DashboardTab = uitab(app.TabGroup0);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadDataButton = uibutton(app.DashboardTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Select DICOM directory'};
            app.LoadDataButton.Text = ' Load data';
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = '     Exit';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.ImagedimensionsPanel = uipanel(app.DashboardTab);
            app.ImagedimensionsPanel.Title = 'Image dimensions';
            app.x1EditFieldLabel = uilabel(app.ImagedimensionsPanel);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.ImagedimensionsPanel, 'numeric');
            app.x1EditField.Limits = [1 Inf];
            app.x1EditField.ValueDisplayFormat = '%.0f';
            app.x1EditField.ValueChangedFcn = createCallbackFcn(app, @DimensionsChanged, true);
            app.x1EditField.Value = 1;
            app.x2EditFieldLabel = uilabel(app.ImagedimensionsPanel);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.ImagedimensionsPanel, 'numeric');
            app.x2EditField.Limits = [1 Inf];
            app.x2EditField.ValueDisplayFormat = '%.0f';
            app.x2EditField.ValueChangedFcn = createCallbackFcn(app, @DimensionsChanged, true);
            app.x2EditField.Value = 1;
            app.y1EditFieldLabel = uilabel(app.ImagedimensionsPanel);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.ImagedimensionsPanel, 'numeric');
            app.y1EditField.Limits = [1 Inf];
            app.y1EditField.ValueDisplayFormat = '%.0f';
            app.y1EditField.ValueChangedFcn = createCallbackFcn(app, @DimensionsChanged, true);
            app.y1EditField.Value = 1;
            app.y2EditFieldLabel = uilabel(app.ImagedimensionsPanel);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.ImagedimensionsPanel, 'numeric');
            app.y2EditField.Limits = [1 Inf];
            app.y2EditField.ValueDisplayFormat = '%.0f';
            app.y2EditField.ValueChangedFcn = createCallbackFcn(app, @DimensionsChanged, true);
            app.y2EditField.Value = 1;
            app.ImageIntensityPanel = uipanel(app.DashboardTab);
            app.ImageIntensityPanel.Title = 'Image Intensity';
            app.minEditFieldLabel = uilabel(app.ImageIntensityPanel);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.ImageIntensityPanel, 'numeric');
            app.minEditField.Limits = [0 Inf];
            app.minEditField.ValueDisplayFormat = '%.0f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @DimensionsChanged, true);
            app.maxEditFieldLabel = uilabel(app.ImageIntensityPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.ImageIntensityPanel, 'numeric');
            app.maxEditField.Limits = [1 Inf];
            app.maxEditField.ValueDisplayFormat = '%.0f';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @DimensionsChanged, true);
            app.maxEditField.Value = 400;
            app.PhysioButton = uibutton(app.DashboardTab, 'push');
            app.PhysioButton.ButtonPushedFcn = createCallbackFcn(app, @PhysioButtonPushed, true);
            app.PhysioButton.Tooltip = {'Calculate physio traces'};
            app.PhysioButton.Text = {'Physio'; ''};
            app.TracepositionsPanel = uipanel(app.DashboardTab);
            app.TracepositionsPanel.Title = 'Trace positions';
            app.TracexEditFieldLabel = uilabel(app.TracepositionsPanel);
            app.TracexEditFieldLabel.Text = 'x / vert';
            app.TracexEditField = uieditfield(app.TracepositionsPanel, 'numeric');
            app.TracexEditField.Limits = [1 Inf];
            app.TracexEditField.ValueDisplayFormat = '%.0f';
            app.TracexEditField.ValueChangedFcn = createCallbackFcn(app, @TracexEditFieldValueChanged, true);
            app.TracexEditField.Value = 1;
            app.TraceyEditFieldLabel = uilabel(app.TracepositionsPanel);
            app.TraceyEditFieldLabel.Text = 'y / hor';
            app.TraceyEditField = uieditfield(app.TracepositionsPanel, 'numeric');
            app.TraceyEditField.Limits = [1 Inf];
            app.TraceyEditField.ValueDisplayFormat = '%.0f';
            app.TraceyEditField.ValueChangedFcn = createCallbackFcn(app, @TraceyEditFieldValueChanged, true);
            app.TraceyEditField.Value = 1;
            app.Tracex1EditFieldLabel = uilabel(app.TracepositionsPanel);
            app.Tracex1EditFieldLabel.Text = 'x1';
            app.Tracex1EditField = uieditfield(app.TracepositionsPanel, 'numeric');
            app.Tracex1EditField.Limits = [1 Inf];
            app.Tracex1EditField.ValueDisplayFormat = '%.0f';
            app.Tracex1EditField.ValueChangedFcn = createCallbackFcn(app, @TraceDiagEditFieldValueChanged, true);
            app.Tracex1EditField.Value = 1;
            app.Tracey1EditFieldLabel = uilabel(app.TracepositionsPanel);
            app.Tracey1EditFieldLabel.Text = 'y1';
            app.Tracey1EditField = uieditfield(app.TracepositionsPanel, 'numeric');
            app.Tracey1EditField.Limits = [1 Inf];
            app.Tracey1EditField.ValueDisplayFormat = '%.0f';
            app.Tracey1EditField.ValueChangedFcn = createCallbackFcn(app, @TraceDiagEditFieldValueChanged, true);
            app.Tracey1EditField.Value = 1;
            app.Tracex2EditFieldLabel = uilabel(app.TracepositionsPanel);
            app.Tracex2EditFieldLabel.Text = 'x2';
            app.Tracex2EditField = uieditfield(app.TracepositionsPanel, 'numeric');
            app.Tracex2EditField.Limits = [1 Inf];
            app.Tracex2EditField.ValueDisplayFormat = '%.0f';
            app.Tracex2EditField.ValueChangedFcn = createCallbackFcn(app, @TraceDiagEditFieldValueChanged, true);
            app.Tracex2EditField.Value = 1;
            app.Tracey2EditFieldLabel = uilabel(app.TracepositionsPanel);
            app.Tracey2EditFieldLabel.Text = 'y2';
            app.Tracey2EditField = uieditfield(app.TracepositionsPanel, 'numeric');
            app.Tracey2EditField.Limits = [1 Inf];
            app.Tracey2EditField.ValueDisplayFormat = '%.0f';
            app.Tracey2EditField.ValueChangedFcn = createCallbackFcn(app, @TraceDiagEditFieldValueChanged, true);
            app.Tracey2EditField.Value = 1;
            app.Lamp2 = uilamp(app.DashboardTab);
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Tooltip = {'Export to Dicom'};
            app.ExportDCMButton.Text = 'Export DCM';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.Lamp3 = uilamp(app.DashboardTab);
            app.LoadSelectButton = uibutton(app.DashboardTab, 'push');
            app.LoadSelectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSelectButtonPushed, true);
            app.LoadSelectButton.Tooltip = {'Load key images from file'};
            app.LoadSelectButton.Text = ' Load Select';
            app.TulipImage = uiimage(app.UIFigure);
            app.Credits = uilabel(app.UIFigure);
            app.Credits.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.RealTimeCineLabel = uilabel(app.UIFigure);
            app.RealTimeCineLabel.Text = 'Real Time Cine';
            app.TabGroup1 = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup1);
            app.DataTab.Title = 'Data';
            app.ImageInfoPanel = uipanel(app.DataTab);
            app.ImageInfoPanel.Title = 'Image information';
            app.dimxEditFieldLabel = uilabel(app.ImageInfoPanel);
            app.dimxEditFieldLabel.Text = 'dimx';
            app.dimxEditField = uieditfield(app.ImageInfoPanel, 'numeric');
            app.dimxEditField.Editable = 'off';
            app.dimxEditField.Value = 1;
            app.dimyEditFieldLabel = uilabel(app.ImageInfoPanel);
            app.dimyEditFieldLabel.Text = 'dimy';
            app.dimyEditField = uieditfield(app.ImageInfoPanel, 'numeric');
            app.dimyEditField.Editable = 'off';
            app.dimyEditField.Value = 1;
            app.imagesEditFieldLabel = uilabel(app.ImageInfoPanel);
            app.imagesEditFieldLabel.Text = '# images';
            app.imagesEditField = uieditfield(app.ImageInfoPanel, 'numeric');
            app.imagesEditField.Editable = 'off';
            app.imagesEditField.Value = 1;
            app.slicesEditFieldLabel = uilabel(app.ImageInfoPanel);
            app.slicesEditFieldLabel.Text = 'slices';
            app.slicesEditField = uieditfield(app.ImageInfoPanel, 'numeric');
            app.slicesEditField.Editable = 'off';
            app.slicesEditField.Value = 1;
            app.timeframesEditFieldLabel = uilabel(app.ImageInfoPanel);
            app.timeframesEditFieldLabel.Text = '# time frames';
            app.timeframesEditField = uieditfield(app.ImageInfoPanel, 'numeric');
            app.timeframesEditField.Editable = 'off';
            app.timeframesEditField.Value = 1;
            app.DicomdirectoryEditFieldLabel = uilabel(app.ImageInfoPanel);
            app.DicomdirectoryEditFieldLabel.Text = 'Dicom directory';
            app.DicomdirectoryEditField = uieditfield(app.ImageInfoPanel, 'text');
            app.DicomdirectoryEditField.Editable = 'off';
            app.DCMInfoPanel = uipanel(app.DataTab);
            app.DCMInfoPanel.Title = 'Dicom information';
            app.DCMInfo = uitextarea(app.DCMInfoPanel);
            app.DCMInfo.Editable = 'off';
            app.CineTab = uitab(app.TabGroup1);
            app.CineTab.Title = 'Cine';
            app.MovieFig_11 = uiaxes(app.CineTab);
            app.MovieFig_11.XLim = [0 255];
            app.MovieFig_11.YLim = [0 255];
            app.MovieFig_11.XTick = [];
            app.MovieFig_11.YTick = [];
            app.MovieFig_12 = uiaxes(app.CineTab);
            app.MovieFig_12.XLim = [0 255];
            app.MovieFig_12.YLim = [0 255];
            app.MovieFig_12.XTick = [];
            app.MovieFig_12.YTick = [];
            app.MovieFig_13 = uiaxes(app.CineTab);
            app.MovieFig_13.XLim = [0 255];
            app.MovieFig_13.YLim = [0 255];
            app.MovieFig_13.XTick = [];
            app.MovieFig_13.YTick = [];
            app.MovieFig_14 = uiaxes(app.CineTab);
            app.MovieFig_14.XLim = [0 255];
            app.MovieFig_14.YLim = [0 255];
            app.MovieFig_14.XTick = [];
            app.MovieFig_14.YTick = [];
            app.MovieFig_16 = uiaxes(app.CineTab);
            app.MovieFig_16.XLim = [0 255];
            app.MovieFig_16.YLim = [0 255];
            app.MovieFig_16.XTick = [];
            app.MovieFig_16.YTick = [];
            app.MovieFig_17 = uiaxes(app.CineTab);
            app.MovieFig_17.XLim = [0 255];
            app.MovieFig_17.YLim = [0 255];
            app.MovieFig_17.XTick = [];
            app.MovieFig_17.YTick = [];
            app.MovieFig_18 = uiaxes(app.CineTab);
            app.MovieFig_18.XLim = [0 255];
            app.MovieFig_18.YLim = [0 255];
            app.MovieFig_18.XTick = [];
            app.MovieFig_18.YTick = [];
            app.MovieFig_19 = uiaxes(app.CineTab);
            app.MovieFig_19.XLim = [0 255];
            app.MovieFig_19.YLim = [0 255];
            app.MovieFig_19.XTick = [];
            app.MovieFig_19.YTick = [];
            app.MovieFig_21 = uiaxes(app.CineTab);
            app.MovieFig_21.XLim = [0 255];
            app.MovieFig_21.YLim = [0 255];
            app.MovieFig_21.XTick = [];
            app.MovieFig_21.YTick = [];
            app.MovieFig_31 = uiaxes(app.CineTab);
            app.MovieFig_31.XLim = [0 255];
            app.MovieFig_31.YLim = [0 255];
            app.MovieFig_31.XTick = [];
            app.MovieFig_31.YTick = [];
            app.MovieFig_41 = uiaxes(app.CineTab);
            app.MovieFig_41.XLim = [0 255];
            app.MovieFig_41.YLim = [0 255];
            app.MovieFig_41.XTick = [];
            app.MovieFig_41.YTick = [];
            app.MovieFig_51 = uiaxes(app.CineTab);
            app.MovieFig_51.XLim = [0 255];
            app.MovieFig_51.YLim = [0 255];
            app.MovieFig_51.XTick = [];
            app.MovieFig_51.YTick = [];
            app.MovieFig_61 = uiaxes(app.CineTab);
            app.MovieFig_61.XLim = [0 255];
            app.MovieFig_61.YLim = [0 255];
            app.MovieFig_61.XTick = [];
            app.MovieFig_61.YTick = [];
            app.MovieFig_22 = uiaxes(app.CineTab);
            app.MovieFig_22.XLim = [0 255];
            app.MovieFig_22.YLim = [0 255];
            app.MovieFig_22.XTick = [];
            app.MovieFig_22.YTick = [];
            app.MovieFig_23 = uiaxes(app.CineTab);
            app.MovieFig_23.XLim = [0 255];
            app.MovieFig_23.YLim = [0 255];
            app.MovieFig_23.XTick = [];
            app.MovieFig_23.YTick = [];
            app.MovieFig_24 = uiaxes(app.CineTab);
            app.MovieFig_24.XLim = [0 255];
            app.MovieFig_24.YLim = [0 255];
            app.MovieFig_24.XTick = [];
            app.MovieFig_24.YTick = [];
            app.MovieFig_26 = uiaxes(app.CineTab);
            app.MovieFig_26.XLim = [0 255];
            app.MovieFig_26.YLim = [0 255];
            app.MovieFig_26.XTick = [];
            app.MovieFig_26.YTick = [];
            app.MovieFig_27 = uiaxes(app.CineTab);
            app.MovieFig_27.XLim = [0 255];
            app.MovieFig_27.YLim = [0 255];
            app.MovieFig_27.XTick = [];
            app.MovieFig_27.YTick = [];
            app.MovieFig_28 = uiaxes(app.CineTab);
            app.MovieFig_28.XLim = [0 255];
            app.MovieFig_28.YLim = [0 255];
            app.MovieFig_28.XTick = [];
            app.MovieFig_28.YTick = [];
            app.MovieFig_29 = uiaxes(app.CineTab);
            app.MovieFig_29.XLim = [0 255];
            app.MovieFig_29.YLim = [0 255];
            app.MovieFig_29.XTick = [];
            app.MovieFig_29.YTick = [];
            app.MovieFig_32 = uiaxes(app.CineTab);
            app.MovieFig_32.XLim = [0 255];
            app.MovieFig_32.YLim = [0 255];
            app.MovieFig_32.XTick = [];
            app.MovieFig_32.YTick = [];
            app.MovieFig_33 = uiaxes(app.CineTab);
            app.MovieFig_33.XLim = [0 255];
            app.MovieFig_33.YLim = [0 255];
            app.MovieFig_33.XTick = [];
            app.MovieFig_33.YTick = [];
            app.MovieFig_34 = uiaxes(app.CineTab);
            app.MovieFig_34.XLim = [0 255];
            app.MovieFig_34.YLim = [0 255];
            app.MovieFig_34.XTick = [];
            app.MovieFig_34.YTick = [];
            app.MovieFig_36 = uiaxes(app.CineTab);
            app.MovieFig_36.XLim = [0 255];
            app.MovieFig_36.YLim = [0 255];
            app.MovieFig_36.XTick = [];
            app.MovieFig_36.YTick = [];
            app.MovieFig_37 = uiaxes(app.CineTab);
            app.MovieFig_37.XLim = [0 255];
            app.MovieFig_37.YLim = [0 255];
            app.MovieFig_37.XTick = [];
            app.MovieFig_37.YTick = [];
            app.MovieFig_38 = uiaxes(app.CineTab);
            app.MovieFig_38.XLim = [0 255];
            app.MovieFig_38.YLim = [0 255];
            app.MovieFig_38.XTick = [];
            app.MovieFig_38.YTick = [];
            app.MovieFig_39 = uiaxes(app.CineTab);
            app.MovieFig_39.XLim = [0 255];
            app.MovieFig_39.YLim = [0 255];
            app.MovieFig_39.XTick = [];
            app.MovieFig_39.YTick = [];
            app.MovieFig_42 = uiaxes(app.CineTab);
            app.MovieFig_42.XLim = [0 255];
            app.MovieFig_42.YLim = [0 255];
            app.MovieFig_42.XTick = [];
            app.MovieFig_42.YTick = [];
            app.MovieFig_43 = uiaxes(app.CineTab);
            app.MovieFig_43.XLim = [0 255];
            app.MovieFig_43.YLim = [0 255];
            app.MovieFig_43.XTick = [];
            app.MovieFig_43.YTick = [];
            app.MovieFig_44 = uiaxes(app.CineTab);
            app.MovieFig_44.XLim = [0 255];
            app.MovieFig_44.YLim = [0 255];
            app.MovieFig_44.XTick = [];
            app.MovieFig_44.YTick = [];
            app.MovieFig_46 = uiaxes(app.CineTab);
            app.MovieFig_46.XLim = [0 255];
            app.MovieFig_46.YLim = [0 255];
            app.MovieFig_46.XTick = [];
            app.MovieFig_46.YTick = [];
            app.MovieFig_47 = uiaxes(app.CineTab);
            app.MovieFig_47.XLim = [0 255];
            app.MovieFig_47.YLim = [0 255];
            app.MovieFig_47.XTick = [];
            app.MovieFig_47.YTick = [];
            app.MovieFig_48 = uiaxes(app.CineTab);
            app.MovieFig_48.XLim = [0 255];
            app.MovieFig_48.YLim = [0 255];
            app.MovieFig_48.XTick = [];
            app.MovieFig_48.YTick = [];
            app.MovieFig_49 = uiaxes(app.CineTab);
            app.MovieFig_49.XLim = [0 255];
            app.MovieFig_49.YLim = [0 255];
            app.MovieFig_49.XTick = [];
            app.MovieFig_49.YTick = [];
            app.MovieFig_52 = uiaxes(app.CineTab);
            app.MovieFig_52.XLim = [0 255];
            app.MovieFig_52.YLim = [0 255];
            app.MovieFig_52.XTick = [];
            app.MovieFig_52.YTick = [];
            app.MovieFig_53 = uiaxes(app.CineTab);
            app.MovieFig_53.XLim = [0 255];
            app.MovieFig_53.YLim = [0 255];
            app.MovieFig_53.XTick = [];
            app.MovieFig_53.YTick = [];
            app.MovieFig_54 = uiaxes(app.CineTab);
            app.MovieFig_54.XLim = [0 255];
            app.MovieFig_54.YLim = [0 255];
            app.MovieFig_54.XTick = [];
            app.MovieFig_54.YTick = [];
            app.MovieFig_62 = uiaxes(app.CineTab);
            app.MovieFig_62.XLim = [0 255];
            app.MovieFig_62.YLim = [0 255];
            app.MovieFig_62.XTick = [];
            app.MovieFig_62.YTick = [];
            app.MovieFig_63 = uiaxes(app.CineTab);
            app.MovieFig_63.XLim = [0 255];
            app.MovieFig_63.YLim = [0 255];
            app.MovieFig_63.XTick = [];
            app.MovieFig_63.YTick = [];
            app.MovieFig_64 = uiaxes(app.CineTab);
            app.MovieFig_64.XLim = [0 255];
            app.MovieFig_64.YLim = [0 255];
            app.MovieFig_64.XTick = [];
            app.MovieFig_64.YTick = [];
            app.MovieFig_57 = uiaxes(app.CineTab);
            app.MovieFig_57.XLim = [0 255];
            app.MovieFig_57.YLim = [0 255];
            app.MovieFig_57.XTick = [];
            app.MovieFig_57.YTick = [];
            app.MovieFig_56 = uiaxes(app.CineTab);
            app.MovieFig_56.XLim = [0 255];
            app.MovieFig_56.YLim = [0 255];
            app.MovieFig_56.XTick = [];
            app.MovieFig_56.YTick = [];
            app.MovieFig_58 = uiaxes(app.CineTab);
            app.MovieFig_58.XLim = [0 255];
            app.MovieFig_58.YLim = [0 255];
            app.MovieFig_58.XTick = [];
            app.MovieFig_58.YTick = [];
            app.MovieFig_59 = uiaxes(app.CineTab);
            app.MovieFig_59.XLim = [0 255];
            app.MovieFig_59.YLim = [0 255];
            app.MovieFig_59.XTick = [];
            app.MovieFig_59.YTick = [];
            app.MovieFig_66 = uiaxes(app.CineTab);
            app.MovieFig_66.XLim = [0 255];
            app.MovieFig_66.YLim = [0 255];
            app.MovieFig_66.XTick = [];
            app.MovieFig_66.YTick = [];
            app.MovieFig_68 = uiaxes(app.CineTab);
            app.MovieFig_68.XLim = [0 255];
            app.MovieFig_68.YLim = [0 255];
            app.MovieFig_68.XTick = [];
            app.MovieFig_68.YTick = [];
            app.MovieFig_67 = uiaxes(app.CineTab);
            app.MovieFig_67.XLim = [0 255];
            app.MovieFig_67.YLim = [0 255];
            app.MovieFig_67.XTick = [];
            app.MovieFig_67.YTick = [];
            app.MovieFig_69 = uiaxes(app.CineTab);
            app.MovieFig_69.XLim = [0 255];
            app.MovieFig_69.YLim = [0 255];
            app.MovieFig_69.XTick = [];
            app.MovieFig_69.YTick = [];
            app.CenterPanel = uipanel(app.CineTab);
            app.CenterPanel.Title = 'Center';
            app.MovieFig_15 = uiaxes(app.CenterPanel);
            app.MovieFig_15.XLim = [0 255];
            app.MovieFig_15.YLim = [0 255];
            app.MovieFig_15.XTick = [];
            app.MovieFig_15.YTick = [];
            app.MovieFig_25 = uiaxes(app.CenterPanel);
            app.MovieFig_25.XLim = [0 255];
            app.MovieFig_25.YLim = [0 255];
            app.MovieFig_25.XTick = [];
            app.MovieFig_25.YTick = [];
            app.MovieFig_35 = uiaxes(app.CenterPanel);
            app.MovieFig_35.XLim = [0 255];
            app.MovieFig_35.YLim = [0 255];
            app.MovieFig_35.XTick = [];
            app.MovieFig_35.YTick = [];
            app.MovieFig_45 = uiaxes(app.CenterPanel);
            app.MovieFig_45.XLim = [0 255];
            app.MovieFig_45.YLim = [0 255];
            app.MovieFig_45.XTick = [];
            app.MovieFig_45.YTick = [];
            app.MovieFig_55 = uiaxes(app.CenterPanel);
            app.MovieFig_55.XLim = [0 255];
            app.MovieFig_55.YLim = [0 255];
            app.MovieFig_55.XTick = [];
            app.MovieFig_55.YTick = [];
            app.MovieFig_65 = uiaxes(app.CenterPanel);
            app.MovieFig_65.XLim = [0 255];
            app.MovieFig_65.YLim = [0 255];
            app.MovieFig_65.XTick = [];
            app.MovieFig_65.YTick = [];
            app.Right1 = uibutton(app.CineTab, 'push');
            app.Right1.ButtonPushedFcn = createCallbackFcn(app, @Right1ButtonPushed, true);
            app.Right1.Text = '=>';
            app.Left1 = uibutton(app.CineTab, 'push');
            app.Left1.ButtonPushedFcn = createCallbackFcn(app, @Left1ButtonPushed, true);
            app.Left1.Text = '<=';
            app.S1EditField = uieditfield(app.CineTab, 'numeric');
            app.S1EditField.ValueChangedFcn = createCallbackFcn(app, @S1EditFieldValueChanged, true);
            app.Right2 = uibutton(app.CineTab, 'push');
            app.Right2.ButtonPushedFcn = createCallbackFcn(app, @Right2ButtonPushed, true);
            app.Right2.Text = '=>';
            app.Left2 = uibutton(app.CineTab, 'push');
            app.Left2.ButtonPushedFcn = createCallbackFcn(app, @Left2ButtonPushed, true);
            app.Left2.Text = '<=';
            app.S2EditField = uieditfield(app.CineTab, 'numeric');
            app.S2EditField.ValueChangedFcn = createCallbackFcn(app, @S2EditFieldValueChanged, true);
            app.Right3 = uibutton(app.CineTab, 'push');
            app.Right3.ButtonPushedFcn = createCallbackFcn(app, @Right3ButtonPushed, true);
            app.Right3.Text = '=>';
            app.Left3 = uibutton(app.CineTab, 'push');
            app.Left3.ButtonPushedFcn = createCallbackFcn(app, @Left3ButtonPushed, true);
            app.Left3.Text = '<=';
            app.S3EditField = uieditfield(app.CineTab, 'numeric');
            app.S3EditField.ValueChangedFcn = createCallbackFcn(app, @S3EditFieldValueChanged, true);
            app.Right4 = uibutton(app.CineTab, 'push');
            app.Right4.ButtonPushedFcn = createCallbackFcn(app, @Right4ButtonPushed, true);
            app.Right4.Text = '=>';
            app.Left4 = uibutton(app.CineTab, 'push');
            app.Left4.ButtonPushedFcn = createCallbackFcn(app, @Left4ButtonPushed, true);
            app.Left4.Text = '<=';
            app.S4EditField = uieditfield(app.CineTab, 'numeric');
            app.S4EditField.ValueChangedFcn = createCallbackFcn(app, @S4EditFieldValueChanged, true);
            app.Right5 = uibutton(app.CineTab, 'push');
            app.Right5.ButtonPushedFcn = createCallbackFcn(app, @Right5ButtonPushed, true);
            app.Right5.Text = '=>';
            app.Left5 = uibutton(app.CineTab, 'push');
            app.Left5.ButtonPushedFcn = createCallbackFcn(app, @Left5ButtonPushed, true);
            app.Left5.Text = '<=';
            app.S5EditField = uieditfield(app.CineTab, 'numeric');
            app.S5EditField.ValueChangedFcn = createCallbackFcn(app, @S5EditFieldValueChanged, true);
            app.Right6 = uibutton(app.CineTab, 'push');
            app.Right6.ButtonPushedFcn = createCallbackFcn(app, @Right6ButtonPushed, true);
            app.Right6.Text = '=>';
            app.Left6 = uibutton(app.CineTab, 'push');
            app.Left6.ButtonPushedFcn = createCallbackFcn(app, @Left6ButtonPushed, true);
            app.Left6.Text = '<=';
            app.S6EditField = uieditfield(app.CineTab, 'numeric');
            app.S6EditField.ValueChangedFcn = createCallbackFcn(app, @S6EditFieldValueChanged, true);
            app.SliceListViewField1 = uieditfield(app.CineTab, 'numeric');
            app.SliceListViewField1.ValueDisplayFormat = '%.0f';
            app.SliceListViewField1.Editable = 'off';
            app.SliceListViewField2 = uieditfield(app.CineTab, 'numeric');
            app.SliceListViewField2.ValueDisplayFormat = '%.0f';
            app.SliceListViewField2.Editable = 'off';
            app.SliceListViewField3 = uieditfield(app.CineTab, 'numeric');
            app.SliceListViewField3.ValueDisplayFormat = '%.0f';
            app.SliceListViewField3.Editable = 'off';
            app.SliceListViewField4 = uieditfield(app.CineTab, 'numeric');
            app.SliceListViewField4.ValueDisplayFormat = '%.0f';
            app.SliceListViewField4.Editable = 'off';
            app.SliceListViewField5 = uieditfield(app.CineTab, 'numeric');
            app.SliceListViewField5.ValueDisplayFormat = '%.0f';
            app.SliceListViewField5.Editable = 'off';
            app.SliceListViewField6 = uieditfield(app.CineTab, 'numeric');
            app.SliceListViewField6.ValueDisplayFormat = '%.0f';
            app.SliceListViewField6.Editable = 'off';
            app.SliceLabel = uilabel(app.CineTab);
            app.SliceLabel.Text = 'Slice';
            app.SliceUp = uibutton(app.CineTab, 'push');
            app.SliceUp.ButtonPushedFcn = createCallbackFcn(app, @SliceUpButtonPushed, true);
            app.SliceUp.Text = {'/\'; '||'};
            app.SliceDown = uibutton(app.CineTab, 'push');
            app.SliceDown.ButtonPushedFcn = createCallbackFcn(app, @SliceDownButtonPushed, true);
            app.SliceDown.Text = {'||'; '\/'};
            app.Navigators1Tab = uitab(app.TabGroup1);
            app.Navigators1Tab.Title = 'Navigators 1';
            app.PhysioFig4 = uiaxes(app.Navigators1Tab);
            title(app.PhysioFig4, 'Line segment')
            xlabel(app.PhysioFig4, 'Image')
            ylabel(app.PhysioFig4, 'Signal')
            app.PhysioFig4.YTick = [];
            app.PhysioFig5 = uiaxes(app.Navigators1Tab);
            title(app.PhysioFig5, 'PPU')
            xlabel(app.PhysioFig5, 'Image')
            ylabel(app.PhysioFig5, 'Signal')
            app.PhysioFig5.YTick = [];
            app.PhysioFig5.Visible = 'off';
            app.LoadPPUButton = uibutton(app.Navigators1Tab, 'push');
            app.LoadPPUButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPPUButtonPushed, true);
            app.LoadPPUButton.Enable = 'off';
            app.LoadPPUButton.Visible = 'off';
            app.LoadPPUButton.Tooltip = {'Load PPU traces'};
            app.LoadPPUButton.Text = 'Load PPU';
            app.AlignPeaksButton = uibutton(app.Navigators1Tab, 'push');
            app.AlignPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @AlignPeaksButtonPushed, true);
            app.AlignPeaksButton.Tooltip = {'Select DICOM directory'};
            app.AlignPeaksButton.Text = 'Align Peaks';
            app.AlignValleyButton = uibutton(app.Navigators1Tab, 'push');
            app.AlignValleyButton.ButtonPushedFcn = createCallbackFcn(app, @AlignValleyButtonPushed, true);
            app.AlignValleyButton.Tooltip = {'Select DICOM directory'};
            app.AlignValleyButton.Text = 'Align Valleys';
            app.Navigators2Tab = uitab(app.TabGroup1);
            app.Navigators2Tab.Title = 'Navigators 2';
            app.PhysioFig2 = uiaxes(app.Navigators2Tab);
            title(app.PhysioFig2, 'Vertical trace')
            xlabel(app.PhysioFig2, 'Image')
            ylabel(app.PhysioFig2, 'Signal')
            app.PhysioFig2.YTick = [];
            app.PhysioFig3 = uiaxes(app.Navigators2Tab);
            title(app.PhysioFig3, 'Horizontal trace')
            xlabel(app.PhysioFig3, 'Image')
            ylabel(app.PhysioFig3, 'Signal')
            app.PhysioFig3.YTick = [];
            app.PhysioFig1 = uiaxes(app.Navigators2Tab);
            title(app.PhysioFig1, 'Mean')
            xlabel(app.PhysioFig1, 'Image')
            ylabel(app.PhysioFig1, 'Signal')
            app.PhysioFig1.YTick = [];
            app.MOLLITab = uitab(app.TabGroup1);
            app.MOLLITab.Title = 'MOLLI';
            app.LoadMOLLIButton = uibutton(app.MOLLITab, 'push');
            app.LoadMOLLIButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMOLLIButtonPushed, true);
            app.LoadMOLLIButton.Tooltip = {'Select DICOM directory'};
            app.LoadMOLLIButton.Text = 'Load MOLLI';
            app.Lamp5 = uilamp(app.MOLLITab);
            app.MOLLIDCMInfoPanel = uipanel(app.MOLLITab);
            app.MOLLIDCMInfoPanel.Title = 'Dicom information';
            app.MOLLIDCMInfo = uitextarea(app.MOLLIDCMInfoPanel);
            app.MOLLIDCMInfo.Editable = 'off';
            app.MOLLIDCMdirectoryEditFieldLabel = uilabel(app.MOLLITab);
            app.MOLLIDCMdirectoryEditFieldLabel.Text = 'Dicom directory';
            app.MOLLIDCMdirectoryEditField = uieditfield(app.MOLLITab, 'text');
            app.MOLLIDCMdirectoryEditField.Editable = 'off';
            app.MOLLINumberofslicesEditFieldLabel = uilabel(app.MOLLITab);
            app.MOLLINumberofslicesEditFieldLabel.Text = 'Number of slices';
            app.MOLLINumberofslicesEditField = uieditfield(app.MOLLITab, 'numeric');
            app.MOLLINumberofslicesEditField.Limits = [1 Inf];
            app.MOLLINumberofslicesEditField.ValueDisplayFormat = '%.0f';
            app.MOLLINumberofslicesEditField.Value = 1;
            app.MOLLITriggerTimesEditFieldLabel = uilabel(app.MOLLITab);
            app.MOLLITriggerTimesEditFieldLabel.Text = 'Trigger times';
            app.MOLLITriggerTimesEditField = uieditfield(app.MOLLITab, 'text');
            app.MOLLITriggerTimesEditField.Editable = 'off';
            app.MOLLIInversionTimesEditFieldLabel = uilabel(app.MOLLITab);
            app.MOLLIInversionTimesEditFieldLabel.Text = 'Inversion times';
            app.MOLLIInversionTimesEditField = uieditfield(app.MOLLITab, 'text');
            app.MOLLIInversionTimesEditField.Editable = 'off';
            app.ExportMOLLIButton = uibutton(app.MOLLITab, 'push');
            app.ExportMOLLIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMOLLIButtonPushed, true);
            app.ExportMOLLIButton.Tooltip = {'Select DICOM directory'};
            app.ExportMOLLIButton.Text = 'Export MOLLI';
            app.Lamp6 = uilamp(app.MOLLITab);
            app.SettingsTab = uitab(app.TabGroup1);
            app.SettingsTab.Title = 'Settings';
            app.ColorsButtonGroup = uibuttongroup(app.SettingsTab);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.ColorsLabel = uilabel(app.SettingsTab);
            app.ColorsLabel.Text = 'Colors';
            app.MessageBox = uitextarea(app.UIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Value = {'Messages ....'};
            app.BackgroundBox = uitextarea(app.UIFigure);
            app.BackgroundBox.Editable = 'off';
            app.StatusImage = uiimage(app.UIFigure);
            app.statusLabel = uilabel(app.UIFigure);
            app.statusLabel.Text = 'status';
            app.Panel = uipanel(app.UIFigure);
            app.ESV1Button = uibutton(app.Panel, 'push');
            app.ESV1Button.ButtonPushedFcn = createCallbackFcn(app, @ESV1ButtonPushed, true);
            app.ESV1Button.Text = 'ESV';
            app.EDV1Button = uibutton(app.Panel, 'push');
            app.EDV1Button.ButtonPushedFcn = createCallbackFcn(app, @EDV1ButtonPushed, true);
            app.EDV1Button.Text = 'EDV';
            app.ESV2Button = uibutton(app.Panel, 'push');
            app.ESV2Button.ButtonPushedFcn = createCallbackFcn(app, @ESV2ButtonPushed, true);
            app.ESV2Button.Text = 'ESV';
            app.EDV2Button = uibutton(app.Panel, 'push');
            app.EDV2Button.ButtonPushedFcn = createCallbackFcn(app, @EDV2ButtonPushed, true);
            app.EDV2Button.Text = 'EDV';
            app.InspirationLabel = uilabel(app.Panel);
            app.InspirationLabel.Text = 'Inspiration';
            app.ExpirationLabel = uilabel(app.Panel);
            app.ExpirationLabel.Text = 'Expiration';
            app.ESV1ViewField = uieditfield(app.Panel, 'text');
            app.ESV1ViewField.Editable = 'off';
            app.EDV1ViewField = uieditfield(app.Panel, 'text');
            app.EDV1ViewField.Editable = 'off';
            app.ESV2ViewField = uieditfield(app.Panel, 'text');
            app.ESV2ViewField.Editable = 'off';
            app.EDV2ViewField = uieditfield(app.Panel, 'text');
            app.EDV2ViewField.Editable = 'off';
            app.RButtonESV1 = uibutton(app.Panel, 'push');
            app.RButtonESV1.ButtonPushedFcn = createCallbackFcn(app, @RButtonESV1Pushed, true);
            app.RButtonESV1.Text = 'R';
            app.RButtonEDV1 = uibutton(app.Panel, 'push');
            app.RButtonEDV1.ButtonPushedFcn = createCallbackFcn(app, @RButtonEDV1Pushed, true);
            app.RButtonEDV1.Text = 'R';
            app.RButtonESV2 = uibutton(app.Panel, 'push');
            app.RButtonESV2.ButtonPushedFcn = createCallbackFcn(app, @RButtonESV2Pushed, true);
            app.RButtonESV2.Text = 'R';
            app.RButtonEDV2 = uibutton(app.Panel, 'push');
            app.RButtonEDV2.ButtonPushedFcn = createCallbackFcn(app, @RButtonEDV2Pushed, true);
            app.RButtonEDV2.Text = 'R';
            app.GitHubHyperlink = uihyperlink(app.UIFigure);
            app.GitHubHyperlink.URL = 'https://github.com/Moby1971/';
            app.GitHubHyperlink.Text = 'GitHub';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RealTimeCine

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end